classdef CameraSettings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Button                    matlab.ui.control.Button
        BrightnessEditFieldLabel  matlab.ui.control.Label
        BrightnessEditField       matlab.ui.control.NumericEditField
        BrightnessSlider          matlab.ui.control.Slider
        ContrastSlider            matlab.ui.control.Slider
        ContrastEditFieldLabel    matlab.ui.control.Label
        ContrastEditField         matlab.ui.control.NumericEditField
        ExposureSlider            matlab.ui.control.Slider
        ExposureEditFieldLabel    matlab.ui.control.Label
        ExposureEditField         matlab.ui.control.NumericEditField
        GainSlider                matlab.ui.control.Slider
        GainEditFieldLabel        matlab.ui.control.Label
        GainEditField             matlab.ui.control.NumericEditField
    end


    properties (Access = private) 
        CallingApp   % Main app object
        src
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app, mainapp, brightness, contrast, exposure, gain, src)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;
            app.src=src;
            
            % Set axes limits            
            %Brightness
            brightness_limits=propinfo(app.src,'Brightness');
            app.BrightnessSlider.Limits=double(brightness_limits.ConstraintValue);         
            app.BrightnessEditField.Value = double(brightness);
            app.BrightnessSlider.Value = double(brightness);
            
            %Contrast
            contrast_limits=propinfo(app.src,'Contrast');
            app.ContrastSlider.Limits=double(contrast_limits.ConstraintValue);         
            app.ContrastEditField.Value = double(contrast);
            app.ContrastSlider.Value = double(contrast);
            
            %Exposure
            exposure_limits=propinfo(app.src,'Exposure');
            app.ExposureSlider.Limits=double(exposure_limits.ConstraintValue);         
            app.ExposureEditField.Value = double(exposure);
            app.ExposureSlider.Value = double(exposure);
            
            %Gain
            gain_limits=propinfo(app.src,'Gain');
            app.GainSlider.Limits=double(gain_limits.ConstraintValue);         
            app.GainEditField.Value = double(gain);
            app.GainSlider.Value = double(gain);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            % Call main app's public function
            Update_Camera_settings(app.CallingApp, app.BrightnessEditField.Value, app.ContrastEditField.Value, app.ExposureEditField.Value, app.GainEditField.Value);
            
            % Delete the dialog box
            delete(app)
        end

        % Close request function: UIFigure
        function DialogAppCloseRequest(app, event)
            % Enable the Plot Opions button in main app
            app.CallingApp.Options.Enable = 'on';
            
            % Delete the dialog box 
            delete(app)
            
        end

        % Value changed function: BrightnessSlider
        function BrightnessSliderValueChanged(app, event)
            app.BrightnessEditField.Value = round(app.BrightnessSlider.Value);
            
        end

        % Value changed function: BrightnessEditField
        function BrightnessEditFieldValueChanged(app, event)
            app.BrightnessSlider.Value = app.BrightnessEditField.Value;
            
        end

        % Value changed function: ContrastSlider
        function ContrastSliderValueChanged(app, event)
            app.ContrastEditField.Value=round(app.ContrastSlider.Value);
            
        end

        % Value changed function: ContrastEditField
        function ContrastEditFieldValueChanged(app, event)
            
            app.ContrastSlider.Value=app.ContrastEditField.Value;
        end

        % Value changed function: ExposureSlider
        function ExposureSliderValueChanged(app, event)
            app.ExposureEditField.Value=round(app.ExposureSlider.Value);
            
        end

        % Value changed function: ExposureEditField
        function ExposureEditFieldValueChanged(app, event)
            
            app.ExposureSlider.Value=app.ExposureEditField.Value;
        end

        % Value changed function: GainSlider
        function GainSliderValueChanged(app, event)
            app.GainEditField.Value=round(app.GainSlider.Value);
            
        end

        % Value changed function: GainEditField
        function GainEditFieldValueChanged(app, event)
            
            app.GainSlider.Value=app.GainEditField.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Options';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @DialogAppCloseRequest, true);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'OK';
            app.BrightnessEditFieldLabel = uilabel(app.UIFigure);
            app.BrightnessEditFieldLabel.Text = 'Brightness';
            app.BrightnessEditField = uieditfield(app.UIFigure, 'numeric');
            app.BrightnessEditField.RoundFractionalValues = 'on';
            app.BrightnessEditField.ValueChangedFcn = createCallbackFcn(app, @BrightnessEditFieldValueChanged, true);
            app.BrightnessSlider = uislider(app.UIFigure);
            app.BrightnessSlider.Limits = [-100 100];
            app.BrightnessSlider.ValueChangedFcn = createCallbackFcn(app, @BrightnessSliderValueChanged, true);
            app.ContrastSlider = uislider(app.UIFigure);
            app.ContrastSlider.Limits = [-100 100];
            app.ContrastSlider.ValueChangedFcn = createCallbackFcn(app, @ContrastSliderValueChanged, true);
            app.ContrastEditFieldLabel = uilabel(app.UIFigure);
            app.ContrastEditFieldLabel.Text = 'Contrast';
            app.ContrastEditField = uieditfield(app.UIFigure, 'numeric');
            app.ContrastEditField.RoundFractionalValues = 'on';
            app.ContrastEditField.ValueChangedFcn = createCallbackFcn(app, @ContrastEditFieldValueChanged, true);
            app.ExposureSlider = uislider(app.UIFigure);
            app.ExposureSlider.Limits = [-100 100];
            app.ExposureSlider.ValueChangedFcn = createCallbackFcn(app, @ExposureSliderValueChanged, true);
            app.ExposureEditFieldLabel = uilabel(app.UIFigure);
            app.ExposureEditFieldLabel.Text = 'Exposure';
            app.ExposureEditField = uieditfield(app.UIFigure, 'numeric');
            app.ExposureEditField.RoundFractionalValues = 'on';
            app.ExposureEditField.ValueChangedFcn = createCallbackFcn(app, @ExposureEditFieldValueChanged, true);
            app.GainSlider = uislider(app.UIFigure);
            app.GainSlider.Limits = [-100 100];
            app.GainSlider.ValueChangedFcn = createCallbackFcn(app, @GainSliderValueChanged, true);
            app.GainEditFieldLabel = uilabel(app.UIFigure);
            app.GainEditFieldLabel.Text = 'Gain';
            app.GainEditField = uieditfield(app.UIFigure, 'numeric');
            app.GainEditField.RoundFractionalValues = 'on';
            app.GainEditField.ValueChangedFcn = createCallbackFcn(app, @GainEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CameraSettings(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)StartupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end