classdef LBSA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        SLM_Screen                     matlab.ui.control.UIAxes
        AutoUpdateSwitchLabel          matlab.ui.control.Label
        Auto_Update_Switch             matlab.ui.control.Switch
        Auto_Update_Lamp               matlab.ui.control.Lamp
        SLMScreenLabel_2               matlab.ui.control.Label
        FresnellensLampLabel           matlab.ui.control.Label
        Fresnel_lens_Lamp              matlab.ui.control.Lamp
        BlazedgratingLabel_2           matlab.ui.control.Label
        Diffraction_grating_Lamp       matlab.ui.control.Lamp
        OpticalvortexLampLabel         matlab.ui.control.Label
        Optical_vortex_Lamp            matlab.ui.control.Lamp
        BesselbeamLampLabel            matlab.ui.control.Label
        Bessel_beam_Lamp               matlab.ui.control.Lamp
        ApertureLampLabel              matlab.ui.control.Label
        Aperture_Lamp                  matlab.ui.control.Lamp
        GerchbergSaxtonLabel           matlab.ui.control.Label
        GS_Lamp                        matlab.ui.control.Lamp
        ExternalhologramLabel          matlab.ui.control.Label
        Hologram_Lamp                  matlab.ui.control.Lamp
        Start_Button                   matlab.ui.control.StateButton
        PhaseshiftLampLabel            matlab.ui.control.Label
        Phase_shift_Lamp               matlab.ui.control.Lamp
        Width                          matlab.ui.control.NumericEditField
        Height                         matlab.ui.control.NumericEditField
        Save_Button                    matlab.ui.control.Button
        XLabel                         matlab.ui.control.Label
        AirybeamLampLabel              matlab.ui.control.Label
        Airy_beam_Lamp                 matlab.ui.control.Lamp
        TransformationLampLabel        matlab.ui.control.Label
        Transformation_Lamp            matlab.ui.control.Lamp
        RotationLampLabel              matlab.ui.control.Label
        Rotation_Lamp                  matlab.ui.control.Lamp
        PhaseShapingLabel              matlab.ui.control.Label
        SaveHologramLabel              matlab.ui.control.Label
        MakeHologramLabel              matlab.ui.control.Label
        TransformationLabel            matlab.ui.control.Label
        CorrectionLabel                matlab.ui.control.Label
        SinusoidalgratingLabel_2       matlab.ui.control.Label
        Sinusoidal_grating_Lamp        matlab.ui.control.Lamp
        ExternalHologramLabel          matlab.ui.control.Label
        Snapshot_Button                matlab.ui.control.Button
        SLM_Lamp                       matlab.ui.control.Lamp
        SLMResolutionLabel             matlab.ui.control.Label
        Make_Snap                      matlab.ui.control.Label
        SelectacameraDropDownLabel     matlab.ui.control.Label
        Select_a_camera_DropDown       matlab.ui.control.DropDown
        Create_Button                  matlab.ui.control.Button
        ZernikepolynomialsLabel        matlab.ui.control.Label
        Zernike_Lamp                   matlab.ui.control.Lamp
        SLM_Detected_EditField         matlab.ui.control.EditField
        options                        matlab.ui.control.StateButton
        RightPanel                     matlab.ui.container.Panel
        TabGroup                       matlab.ui.container.TabGroup
        External_hologram_Tab          matlab.ui.container.Tab
        Hologram                       matlab.ui.control.UIAxes
        Hologram_Label                 matlab.ui.control.Label
        LoadhologramsPanel             matlab.ui.container.Panel
        List_Box                       matlab.ui.control.ListBox
        External_Hologram_CheckBox     matlab.ui.control.CheckBox
        Load_hologram_Button           matlab.ui.control.Button
        AddtoSLMLabel                  matlab.ui.control.Label
        ClearButton                    matlab.ui.control.Button
        Phase_shaping_Tab              matlab.ui.container.Tab
        Fresnel_lens_Panel             matlab.ui.container.Panel
        Fresnel_CheckBox               matlab.ui.control.CheckBox
        FocusmSpinnerLabel             matlab.ui.control.Label
        focus                          matlab.ui.control.Spinner
        Optical_vortex_Panel           matlab.ui.container.Panel
        Optical_vortex_CheckBox        matlab.ui.control.CheckBox
        TopologicalchargeSpinnerLabel  matlab.ui.control.Label
        Topological_charge_Spinner     matlab.ui.control.Spinner
        Bessel_beam_Panel              matlab.ui.container.Panel
        Bessel_beam_CheckBox           matlab.ui.control.CheckBox
        RefractiveindexLabel           matlab.ui.control.Label
        Refractive_index_EditField     matlab.ui.control.NumericEditField
        ApexangledgLabel               matlab.ui.control.Label
        Axicon_angle_dg_EditField      matlab.ui.control.NumericEditField
        Phase_shift_Panel              matlab.ui.container.Panel
        Phase_shift_CheckBox           matlab.ui.control.CheckBox
        degSpinnerLabel                matlab.ui.control.Label
        deg_Spinner                    matlab.ui.control.Spinner
        Radian                         matlab.ui.control.EditField
        radLabel                       matlab.ui.control.Label
        Diffractiongrating_Panel       matlab.ui.container.Panel
        NumberofgroovesyLabel          matlab.ui.control.Label
        Number_of_grooves_y_Spinner    matlab.ui.control.Spinner
        Grating_vector_x_Label         matlab.ui.control.Label
        Vector_Y                       matlab.ui.control.EditField
        Diffraction_grating_CheckBox   matlab.ui.control.CheckBox
        NumberofgroovesxLabel          matlab.ui.control.Label
        Number_of_grooves_x_Spinner    matlab.ui.control.Spinner
        Grating_vector_y_Label         matlab.ui.control.Label
        Vector_X                       matlab.ui.control.EditField
        Sinusoidal_diffraction_grating_CheckBox  matlab.ui.control.CheckBox
        BlazedgratingLabel             matlab.ui.control.Label
        SinusoidalgratingLabel         matlab.ui.control.Label
        Airy_beam_Panel                matlab.ui.container.Panel
        Airy_CheckBox                  matlab.ui.control.CheckBox
        HeightEditFieldLabel           matlab.ui.control.Label
        Airy_Height_EditField          matlab.ui.control.NumericEditField
        WidthEditFieldLabel            matlab.ui.control.Label
        Airy_Width_EditField           matlab.ui.control.NumericEditField
        TabGroup2                      matlab.ui.container.TabGroup
        TransformationTab              matlab.ui.container.Tab
        Transformation_Panel           matlab.ui.container.Panel
        TranslationxSpinnerLabel       matlab.ui.control.Label
        Translation_x_Spinner          matlab.ui.control.Spinner
        TranslationySpinnerLabel       matlab.ui.control.Label
        Translation_y_Spinner          matlab.ui.control.Spinner
        RotationSpinnerLabel           matlab.ui.control.Label
        Rotation_Spinner               matlab.ui.control.Spinner
        Translation_Rotation_CheckBox  matlab.ui.control.CheckBox
        VortexonlySwitchLabel          matlab.ui.control.Label
        VortexonlySwitch               matlab.ui.control.ToggleSwitch
        Aperture_Panel                 matlab.ui.container.Panel
        DiameterpxEditFieldLabel       matlab.ui.control.Label
        Aperture_Diameter              matlab.ui.control.NumericEditField
        Aperture_CheckBox              matlab.ui.control.CheckBox
        OtherparametersPanel           matlab.ui.container.Panel
        PixelsizeumEditFieldLabel      matlab.ui.control.Label
        PixelsizeEditField             matlab.ui.control.NumericEditField
        WavelengthnmEditFieldLabel     matlab.ui.control.Label
        WavelengthEditField            matlab.ui.control.NumericEditField
        Correction_Tab                 matlab.ui.container.Tab
        Correction                     matlab.ui.control.UIAxes
        Gerchberg_Saxton_Panel         matlab.ui.container.Panel
        GS_Button                      matlab.ui.control.Button
        Set_up_values_Button           matlab.ui.control.Button
        ApertureEditFieldLabel         matlab.ui.control.Label
        GS_Aperture_EditField          matlab.ui.control.NumericEditField
        NumberofgroovesLabel           matlab.ui.control.Label
        Gratingvector1pxLabel          matlab.ui.control.Label
        First_grating_Button           matlab.ui.control.Button
        Second_grating_Button          matlab.ui.control.Button
        Axes_Button_Group              matlab.ui.container.ButtonGroup
        x_Button                       matlab.ui.control.RadioButton
        y_Button                       matlab.ui.control.RadioButton
        Number_of_grooves_1st_EditField  matlab.ui.control.EditField
        Grating_vector_1st_EditField   matlab.ui.control.EditField
        Grating_vector_2nd_EditField   matlab.ui.control.EditField
        Number_of_grooves_2nd_EditField  matlab.ui.control.EditField
        Aperture_size_ButtonGroup      matlab.ui.container.ButtonGroup
        Original_Button                matlab.ui.control.RadioButton
        Half_Button                    matlab.ui.control.RadioButton
        Quarter_Button                 matlab.ui.control.RadioButton
        Other_Button                   matlab.ui.control.RadioButton
        PaddingEditFieldLabel          matlab.ui.control.Label
        Padding_EditField              matlab.ui.control.EditField
        Load_correction_ButtonGroup    matlab.ui.container.ButtonGroup
        plus                           matlab.ui.control.RadioButton
        minus                          matlab.ui.control.RadioButton
        On_off_correction              matlab.ui.control.CheckBox
        IterationsLabel                matlab.ui.control.Label
        Iterations_EditField           matlab.ui.control.NumericEditField
        Current_iteration_number_EditField  matlab.ui.control.NumericEditField
        CorrectionLabel_2              matlab.ui.control.Label
        ZernikepolynomialsTab          matlab.ui.container.Tab
        Fresnel_lens_Panel_2           matlab.ui.container.Panel
        On_off_Zernike                 matlab.ui.control.CheckBox
        Value_Label                    matlab.ui.control.Label
        Name_Label                     matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
        Label_4                        matlab.ui.control.Label
        Label_5                        matlab.ui.control.Label
        Label_6                        matlab.ui.control.Label
        Label_7                        matlab.ui.control.Label
        Label_8                        matlab.ui.control.Label
        Label_9                        matlab.ui.control.Label
        Label_10                       matlab.ui.control.Label
        Label_11                       matlab.ui.control.Label
        Label_12                       matlab.ui.control.Label
        PistonEditFieldLabel           matlab.ui.control.Label
        Piston_EditField               matlab.ui.control.NumericEditField
        YTiltEditFieldLabel            matlab.ui.control.Label
        Y_Tilt_EditField               matlab.ui.control.NumericEditField
        XTiltEditFieldLabel            matlab.ui.control.Label
        X_Tilt_EditField               matlab.ui.control.NumericEditField
        ObliqueastgmEditFieldLabel     matlab.ui.control.Label
        Oblique_astgm_EditField        matlab.ui.control.NumericEditField
        DefocusLabel                   matlab.ui.control.Label
        Defocuse_EditField             matlab.ui.control.NumericEditField
        VerticalastgmLabel             matlab.ui.control.Label
        Vertical_EditField             matlab.ui.control.NumericEditField
        VerticaltrefoilEditFieldLabel  matlab.ui.control.Label
        Vertical_trefoil_EditField     matlab.ui.control.NumericEditField
        VerticalcomaEditFieldLabel     matlab.ui.control.Label
        Vertical_coma_EditField        matlab.ui.control.NumericEditField
        HorizontalcomaEditFieldLabel   matlab.ui.control.Label
        Horizontal_coma_EditField      matlab.ui.control.NumericEditField
        HorizontaltrefoilEditFieldLabel  matlab.ui.control.Label
        Horizontal_trefoil_EditField   matlab.ui.control.NumericEditField
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        NH = 0;              % Numbers of external holograms
        X;                   % Stores coordination system
        Y;                   % Stores coordination system
        phase_map;           % Empty - no defined hologram
        hologram_3D;         % Cell with all holograms
        hologram_sum;        % Cell with sum of all turn on holograms
        checkbox_sum;        % Cell with sum of logical value of checkboxs 
        pathname;            % Path to the selected hologram
        i = 1;               % Index of the selected hologram
        Iterations = 0;         
        n_1st;
        n_2nd;
        SLM_resolution;      % Contains informations about SLM resolution
        position_diff;       % Sotres distance between two position of the beam
        padding;             % Stores calculate padding      
        grating_vector_diff; % Contains information about grating vectior difference
        half_side;
        center;
        correction_value;    % Stores correcion map
        external_holo;
        adaptor              % Stores the chosen adaptor
        
        device_name;         % Contains information about divece name
        vid;                 % Stores information about video input 
        popup_camera;
        value_camera;
        
        lambda; % Wavelength
        pixel_size; %Pixel size
        fig;
        SLM_axes;
        names_cellstr;
        vortex_copy;
        
        
        %CameraSettings
        DialogApp; %Opens a dialog app to set camera parameters
        brightness;
        contrast;
        exposure;
        gain;
                    %{
   

    vid.FramesPerTrigger = 
    src.ExposureMode = 'manual';
    src.ExposureMode = 'auto';

    src.Gamma = 100;
    src.Hue = 0;
    src.Saturation = 64;
    src.Sharpness = 2;
    src.WhiteBalance = 4600;

    src.WhiteBalanceMode = 'manual';
    src.WhiteBalanceMode = 'auto';
            %}
        end

    methods (Access = private)  
        
% Function returns hologram of a fresnel lens
        function [ F ] = fresnel(app)
            
            step=app.PixelsizeEditField.Value*1e-6;
            f = app.focus.Value;
            l = app.WavelengthEditField.Value*1e-9;
            
            %Coordination system
            x_obr=0; %center of coordination system
            y_obr=0;
            obrx=app.Width.Value*step;
            obry=app.Height.Value*step;
            bokx=x_obr-obrx/2;
            boky=y_obr-obry/2;
            
            F=zeros(app.Height.Value,app.Width.Value);
                for m=1:app.Width.Value
                   for n=1:app.Height.Value
                      x=bokx+m*step;
                      y=boky+n*step;
                      r=sqrt(x.^2+y.^2);
                      F(n,m)=mod((1*pi*r^2)/(l*f),2*pi);
                   end
                end  
         end
       
% Function returns hologram of optical vortex with proper topological charge
         function [vortex_hologram] = vortex(app)
             
             phi = angle(app.X+1i*app.Y);               %Azimuthal angle
             m = app.Topological_charge_Spinner.Value;
             vortex_hologram=mod(m*phi,2*pi);
             
         end
         
% Function returns hologram of a bessel beam
         function [bessel_hologram] = bessel(app)
            %l - wavelength
            %n - refractive index
            %alpha - axicon angle
            
            l = app.WavelengthEditField.Value*1e-9;
            n = app.Refractive_index_EditField.Value;
            alpha = app.Axicon_angle_dg_EditField.Value;
            alpha=alpha*pi/180;
            
            Xb = app.X*app.PixelsizeEditField.Value*1e-6;
            Yb = app.Y*app.PixelsizeEditField.Value*1e-6;

            rho = sqrt(Xb.^2+Yb.^2);            
            
            k=2*pi/l;
            
            kr=k*alpha*(n-1);
            bessel_hologram=mod(kr*rho,2*pi);
            
         end
         
% Phase shift
         function [rad] = phase_rad(app)
             rad = app.deg_Spinner.Value*1/180*pi;
         end
        
% Function returns hologram of a grating
function [grating_hologram] = diff_grating(app,nx,ny)
            % [nx ny] - number of grooves
            %nx = app.Number_of_grooves_x_Spinner.Value;
            %ny = app.Number_of_grooves_y_Spinner.Value;
                
            % Spatial freq
            gx=nx/app.Width.Value; 
            gy=ny/app.Height.Value; 
                
            % Grating hologram
            grating_hologram=mod(2*pi*(app.Y.*gy+app.X.*gx),2*pi); 
         end
         
% Grating vectors
         function [vec] = vector(~, s,g)
             % s - width or height of hologram
             % g - number og grooves (x or y)
             vec = 2*pi/(s/g);
         end

% Cover the hologram with the aperture
         function [I]=cover_with_aperture(~,I,aperture)
             % I - hologram
             [h,w] = size(I);
             centerY=round(h/2);
             centerX=round(w/2);
             
             %Circular aperture
             [columnsInImage, rowsInImage] = meshgrid(1:w, 1:h);
             circlePixels = (rowsInImage - centerY).^2 + (columnsInImage - centerX).^2 <= (aperture/2).^2;

             %Aperture addition
             I(~circlePixels) = 0;
         end
         
% Airy beam         
         function [airy_hologram] = airy(app)
             
             h = app.Y*8e-3;
             w = app.X*8e-3;
             
             A = (w.^3 + h.^3)/3;
             airy_hologram = mod(A,2*pi);
         end
         
% Crop the image to desired size, with respect to its center         
         function [img] = im_crop(~,img,center,half_side)
             
         %center - [x,y] center of the beam
         %half_side - length of half side square that image will be cropped to        
            if length(size(img))~= 2
                 img= im2double(rgb2gray(img));
            end                        
             %Prepare size
                            
             x=center(2)-half_side;
             y=center(1)-half_side;
            
             side=2*half_side;
                            
             %Crop image
             img=imcrop(img,[x y side+1 side+1]);                           
         end

% Calculate Padding         
         function [m_array] = calculate_padding(~, aperture, grating_vector_diff, position_diff, m_holo)
            %aperture - size of the aperture used in algorithm [px]
            %grating_vector_diff - abs difference of two grating vectors [1/px]
            %position_diff - distance between two position of the beam [px]
            %m_holo - diameter of the hologram on slm [px]
            
            grating_vector_diff=abs(grating_vector_diff);
            
            %Calculate size of matrix to padd to
            m_array=aperture*2*pi*position_diff/(m_holo*grating_vector_diff);
         end

% Finding aperture to GS correction

         function [I] = aperture(~,I,aperture,w,h)
            
             %Circular aperture
            centerY=h/2;
            centerX=w/2;
            
            [columnsInImage, rowsInImage] = meshgrid(1:w, 1:h);
            circlePixels = (rowsInImage - centerY).^2 + (columnsInImage - centerX).^2<= (aperture/2).^2;
            mapa=ones(h,w);
            map_aperture=circlePixels.*mapa;
            
            %Aperture addition
            I=map_aperture.*I;   
            
         end
% Send to SLM
         function Send_to_SLM(app,hologram)           
         if app.SLM_Lamp.Color == [0 1 0]
            %Display hologram on the SLM
            imshow(hologram,[0 2*pi],'Parent',app.SLM_axes)
         end
        end

% Get position         
         function [row, col] = get_position(app,vid,map,sum)
             
             map=mod(sum+map,2*pi);
             %map = cover_with_aperture(app,map, app.GS_Aperture_EditField.Value);
             Send_to_SLM(app,map);
             %slm2(map);
             pause(3);
             
             try
                 img = getsnapshot(vid);
             catch
                 disp('No camera detected')
             end
             
             if length(size(img))~= 2
                img= im2double(rgb2gray(img));
             end
             
             %Get position
                figure()
                imshow(img)
                
                zoom on
                pause()
                zoom off
                [col,row] = ginput(1);
                zoom out
                close()
                
         end

         function [row, col] = get_position_2(~,vid)
            
            try
                 img = getsnapshot(vid);
             catch
                 disp('No camera detected')
            end
            
             if length(size(img))~= 2
                img= im2double(rgb2gray(img));
             end
             
             %Get position
                figure()
                imshow(img)
                set(gcf, 'pointer', 'arrow');
                zoom on;
                pause() % you can zoom with your mouse and when your image is okay, you press any key
                zoom off; % to escape the zoom mode
                [col,row] = ginput(2);
                zoom out; % go to the original size of your image
                close()
                
         end
         
         function [hk] = Hermite_poly(~,n)

            if n==0 
                hk = 1;
            elseif n==1
                hk = [2 0];
            else
                
                hkm2 = zeros(1,n+1);
                hkm2(n+1) = 1;
                hkm1 = zeros(1,n+1);
                hkm1(n) = 2;
            
                for k=2:n
                    
                    hk = zeros(1,n+1);
            
                    for e=n-k+1:2:n
                        hk(e) = 2*(hkm1(e+1) - (k-1)*hkm2(e));
                    end
                    
                    hk(n+1) = -2*(k-1)*hkm2(n+1);
                    
                    if k<n
                        hkm2 = hkm1;
                        hkm1 = hk;
                    end
                    
                end
                
            end
         end


% ZERNIKE Polynomials
function z = zernike_function(~,n,m,r,theta)
             
            n = n(:);
            m = m(:);
            r = r(:);
            theta = theta(:);
            length_r = length(r);
            m_abs = abs(m);
            rpowers = [];
            
            for j = 1:length(n)
                rpowers = [rpowers m_abs(j):2:n(j)];
            end
            rpowers = unique(rpowers);

            if rpowers(1)==0
                rpowern = arrayfun(@(p)r.^p,rpowers(2:end),'UniformOutput',false);
                rpowern = cat(2,rpowern{:});
                rpowern = [ones(length_r,1) rpowern];
            else
                rpowern = arrayfun(@(p)r.^p,rpowers,'UniformOutput',false);
                rpowern = cat(2,rpowern{:});
            end
            
            % Polynomials:

            z = zeros(length_r,length(n));
            for j = 1:length(n)
                s = 0:(n(j)-m_abs(j))/2;
                pows = n(j):-2:m_abs(j);
                for k = length(s):-1:1
                    p = (1-2*mod(s(k),2))* ...
                               prod(2:(n(j)-s(k)))/              ...
                               prod(2:s(k))/                     ...
                               prod(2:((n(j)-m_abs(j))/2-s(k)))/ ...
                               prod(2:((n(j)+m_abs(j))/2-s(k)));
                    idx = (pows(k)==rpowers);
                    z(:,j) = z(:,j) + p*rpowern(:,idx);
                end     
            end
            
            % Zernike functions:

            idx_pos = m>0;
            idx_neg = m<0;
            if any(idx_pos)
                z(:,idx_pos) = z(:,idx_pos).*cos(theta*m_abs(idx_pos)');
            end
            if any(idx_neg)
                z(:,idx_neg) = z(:,idx_neg).*sin(theta*m_abs(idx_neg)');

            end
         end
         
         function zernike_map = do_zernike(app,w,h,n,m,coefficient)
            %Functon will calculate proper zernike polynomial Z_n_m and multiply it by
            %specified coeffcient
            
            if coefficient~=0 %In case of 0 value, returns an empty map  

                x = linspace(-1,1,h.Value);
                [X_z,Y_z] = meshgrid(x,x);
                [theta,r] = cart2pol(X_z,Y_z);

                 idx = r<=1;
                 z = nan(size(X_z));
                
                %% calculate polynomial
                z(idx) = zernike_function(app,m,n,r(idx),theta(idx));
                z = padarray(z,[0 (w.Value-h.Value)/2], 0, 'both');
                z(isnan(z))=0;
                zernike_map=mod(z*coefficient,2*pi);
            else
                zernike_map=zeros(h.Value,w.Value);
            end
          end

         
% ON/OFF end hologram
         function SLM_on_off(app)
             
            % Auxiliary variables
            F = app.Fresnel_CheckBox.Value;
            V = app.Optical_vortex_CheckBox.Value;
            B = app.Bessel_beam_CheckBox.Value;
            P = app.Phase_shift_CheckBox.Value;
            D = app.Diffraction_grating_CheckBox.Value;
            A = app.Aperture_CheckBox.Value;
            E = app.External_Hologram_CheckBox.Value;
            G = app.Sinusoidal_diffraction_grating_CheckBox.Value;
            C = app.Airy_CheckBox.Value;
            TR = app.Translation_Rotation_CheckBox.Value;
            GS = app.On_off_correction.Value;
            Z = app.On_off_Zernike.Value;
            
            app.checkbox_sum = F+V+B+P+D+E+G+C+TR+Z;


            % Translation and Rotation of vortex only
            vortex=app.hologram_3D(:,:,2); % Stores original vortex hologram
            
            if TR == 1 && V==1
                if app.VortexonlySwitch.Value=="On" %If vortex only is switch is on
                    if app.Translation_x_Spinner.Value ~= 0 || app.Translation_y_Spinner.Value ~= 0                   
                        vortex = imtranslate(app.hologram_3D(:,:,2), [-app.Translation_x_Spinner.Value app.Translation_y_Spinner.Value]);
                    end
                    if app.Rotation_Spinner.Value ~= 0
                        vortex = imrotate(app.hologram_3D(:,:,2), app.Rotation_Spinner.Value, 'crop');
                    end                
                end
            end
            
            %app.VortexonlySwitch.Value;
            
            % Sum of holograms
            app.hologram_sum = mod(app.hologram_3D(:,:,1)+ ...    % Fresnel lens
                                   vortex+ ...    % Optical vortex
                                   app.hologram_3D(:,:,3)+ ...    % Bessel beam
                                   app.hologram_3D(:,:,4)+ ...    % Phase shift
                                   app.hologram_3D(:,:,5)+ ...    % Diffraction grating
                                   app.hologram_3D(:,:,6)+ ...    % External hologram
                                   app.hologram_3D(:,:,7)+ ...    % Sinusoidal diffraction grating
                                   app.hologram_3D(:,:,8)+ ...    % Airy beam
                                   app.hologram_3D(:,:,9)...     % Zernike Polynomial
                                   ,2*pi); 


            % Correction
            
            if GS == 1
                %if isempty(app.correction_value) ~=0
                    switch app.plus.Value
                        case 1
                            app.hologram_sum = mod(app.hologram_sum + app.correction_value,2*pi);
                            %imshow(app.hologram_sum,[],'Parent',app.SLM_Screen);
                            %Send_to_SLM(app,app.hologram_sum);
                        otherwise
                            app.hologram_sum = mod(app.hologram_sum - app.correction_value,2*pi);
                            %imshow(app.hologram_sum,[],'Parent',app.SLM_Screen);
                            %Send_to_SLM(app,app.hologram_sum);
                    end
                %end
            end
            
            
            % Cover with aperture            
            if A == 1
                if app.Aperture_Diameter.Value ~= 0
                     app.hologram_sum = cover_with_aperture(app, app.hologram_sum,app.Aperture_Diameter.Value);
                end
            end
            
             % Translation and Rotation of all holograms
            if TR == 1
                if app.VortexonlySwitch.Value=="Off" %If vortex only is switch is off    
                    if app.Translation_x_Spinner.Value ~= 0 || app.Translation_y_Spinner.Value ~= 0                   
                        app.hologram_sum = imtranslate(app.hologram_sum, [-app.Translation_x_Spinner.Value app.Translation_y_Spinner.Value]);
                    end
                    if app.Rotation_Spinner.Value ~= 0
                        app.hologram_sum = imrotate(app.hologram_sum, app.Rotation_Spinner.Value, 'crop');
                    end                
                end
            end     


            % SLM hologram - ON/OFF
            if strcmp(app.Auto_Update_Switch.Value, 'off')
                if app.checkbox_sum >= 1 
                        if app.Start_Button.Value == 1
                            imshow(app.hologram_sum,[],'Parent',app.SLM_Screen);
                            app.Start_Button.Value = 0;
                            Send_to_SLM(app,app.hologram_sum);
                        end
                else
                    if app.Start_Button.Value == 1
                        imshow(app.phase_map,[],'Parent',app.SLM_Screen);
                        app.Start_Button.Value = 0;
                        Send_to_SLM(app,app.phase_map);
                    end
                end
            else
                if app.checkbox_sum >= 1
                    imshow(app.hologram_sum,[],'Parent',app.SLM_Screen);
                    Send_to_SLM(app,app.hologram_sum);
                else
                    imshow(app.phase_map,[],'Parent',app.SLM_Screen);
                    Send_to_SLM(app,app.phase_map);
                end
            end 
            
         end
        
        
         function [grating_hologram] = sinusoidal_grating(app,nx,ny)
            % Function returns hologram of a sinusoidal grating
            % [nx ny] - number of grooves
            %nx = app.Number_of_grooves_x_Spinner.Value;
            %ny = app.Number_of_grooves_y_Spinner.Value;
                
            % Spatial freq
            gx=nx/app.Width.Value; 
            gy=ny/app.Height.Value; 
                
            % Grating hologram
            %grating_hologram=mod(pi*(sin()+1),2*pi); 
            grating_hologram=abs((angle(exp(1i*sin(2*pi*app.Y.*gy+2*pi*app.X.*gx)))+1)*pi-0.00001); %-0.00001 protects the function from numerical errors
         end

    end
    
    methods (Access = public)
        
        function Update_Camera_settings(app, brightness, contrast, exposure, gain)
            src=getselectedsource(app.vid);
            app.brightness=brightness;
            src.Brightness=app.brightness;
            
            app.contrast=contrast;
            src.Contrast=app.contrast;
            
            app.exposure=exposure;
            src.Exposure=app.exposure;
            
            app.gain=gain;
            src.Gain=app.gain;
            %{
            src = getselectedsource(vid);

vid.FramesPerTrigger = 1;

src.Brightness = 0;
src.Contrast = 0;
src.Exposure = -6;

src.ExposureMode = 'manual';
src.ExposureMode = 'auto';


src.Gain = 1;
src.Gamma = 100;
src.Hue = 0;
src.Saturation = 64;
src.Sharpness = 2;
src.WhiteBalance = 4600;

src.WhiteBalanceMode = 'manual';
src.WhiteBalanceMode = 'auto';
            %}
            % Re-enable the Options button
            app.options.Enable = 'on';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           
            
 %%----CAMERA PROGRESS BAR           
            %Pop up window
                popupfig = uifigure;
                d = uiprogressdlg(popupfig,'Title','Please Wait',...
                'Message','Opening the application');
                pause(.5)

    
            %% Toolbox
                d.Value = .25; 
                d.Message = 'Checking available toolboxes';
    
                version=ver;
                version2={version.Name};
                indx = find(strcmp(version2, 'Image Acquisition Toolbox'), 1);
                toolbox = 1 - isempty(indx); %If toolbox exists, returns 1
         
                if toolbox == 1
                    disp('Image acquisition toolbox available') 
                else
                    disp('Image acquisition toolbox not available') 
                end
    
                clear version version2 indx
                pause(1)
    
            %% Cameras
                d.Value = .5;
                d.Message = 'Determining connected cameras';
                
                adaptors=imaqhwinfo;
                installed_adaptors = adaptors.InstalledAdaptors;
                
                if length(installed_adaptors)~=0
                    for i=1:1:length(installed_adaptors)
                        info = imaqhwinfo(installed_adaptors{1,i});
                        installed_adaptors_vector(i)=length(info.DeviceInfo);    
                    end
                
                indx = find(installed_adaptors_vector==1);
            
                disp(['Detected cameras: '])
                
                for i=1:1:length(indx)
                    disp(installed_adaptors{1,indx(i)})
                end
                
                pause(1)
                else
                
                disp(['Detected cameras: none']);    
                    
                end
                
               
            %% SLM
                d.Value = .75;
                d.Message = 'Determining SLM resolution';
                
                Screen_positions = get(0,'MonitorPositions');
                No_of_screens = size(Screen_positions,1);
                
                if No_of_screens==1
                    disp(['Detected SLMs: 0'])    
                else
                    No_of_screens=No_of_screens-1;    
                    disp(['Detected SLMs: ' num2str(No_of_screens) ' Resolution: ' num2str(Screen_positions(2,3)) 'x' num2str(Screen_positions(2,4))])    
                end
            
            pause(1)
            %% Close dialog box
                close(d)
                
                %% Choose the camera adaptor
                
                if length(installed_adaptors)~=0
                % Create list box of all adaptors
                Empty = cell(1,0);
                if isequal(Empty, info.DeviceIDs) ~= 1
                    fig_pos=get(popupfig,'Position'); % get the position of the current figure
                    
                    %Text
                    label1 = uilabel(popupfig,...
                    'Position',[fig_pos(3)/2-100 fig_pos(4)/2+30, 200 100],....
                    'HorizontalAlignment','center',...
                    'Text','Choose the camera adaptor:');
                
                    %Listbox
                    lbox = uilistbox(popupfig,...
                    'Position',[fig_pos(3)/2-100 fig_pos(4)/2-50 200 100],...
                        'Items',{installed_adaptors{1,indx}},... 
                    'MultiSelect', 'off');
                    
                    %Button
                    btn = uibutton(popupfig,'state',...
                               'Text', 'Choose',...
                               'Position',[fig_pos(3)/2-50 fig_pos(4)/2-100, 100, 22]);
                    %Click condition          
                    waitfor(btn,'Value',1);
                    app.adaptor=lbox.Value; %Chosen value
                    
                    %% Close figure
                    close(popupfig)
                else 
                     close(popupfig)
                end
                end
               % close(popupfig)
                
                %Pop up window closure
              
   %%----CAMERA PROGRESS BAR          
            
            % Screen parameters 
            Screen_positions = get(0,'MonitorPositions');
            No_of_screens = size(Screen_positions,1);
            
            if No_of_screens == 1
                % SLM not detected
                app.SLM_Lamp.Color = [0 0 0];
                
                % Defines default coordination system vectors
                app.Width.Value = 1920;       % Resolution x
                app.Height.Value = 1080;      % Resolution y
            else
                
                % SLM detected
                app.SLM_Lamp.Color = [0 1 0];
                app.SLM_resolution = Screen_positions(2,:);
                app.Width.Value = app.SLM_resolution(3);
                app.Height.Value = app.SLM_resolution(4);
                
                % Create figure window
                app.fig=figure('Name','SLM','Position',app.SLM_resolution,'MenuBar','none', 'ToolBar', 'none', 'WindowState', 'fullscreen', 'WindowStyle',"modal");
                app.SLM_axes = axes('Parent',app.fig,'Units','pixels','Position',[0 0 app.SLM_resolution(3) app.SLM_resolution(4)]);
                app.Create_Button.Enable = 'off';
                
            end
            
            
            if length(installed_adaptors)~=0
                     
                Empty = cell(1,0);
                if isequal(Empty, info.DeviceIDs) ~= 1
                    info = imaqhwinfo(app.adaptor);
                    num_of_devices = length(info.DeviceInfo);
                    app.vid = videoinput(app.adaptor);
                
                
                    app.names_cellstr={zeros};
                    for k=1:1:num_of_devices
                        dev_info = imaqhwinfo(app.adaptor, k);
                        name=dev_info.DeviceName;
                        app.names_cellstr{k+1} = name;
                        app.Select_a_camera_DropDown.Items(k+1) = app.names_cellstr(k+1);
                        b=k;
                    end
                end
            
            end
    
            
            % Table Zernike
            str = {'Piston';'Y Tilt';'X Tilt';'Oblique astgm';'Defocus';'Vertical';'Vertical trefoil';'Vertical coma';'Horizontal coma';'Horizonatl trefoil'};
            empty = cell(10,1);
           % app.UITable.Data = [str empty];
            
            % Initial component parameters
            app.Hologram.Visible = 'off';
            app.SLM_Screen.Visible = 'off';
            app.Auto_Update_Switch.Value = 'off';
            app.Start_Button.Enable = 'on';
            app.Correction.Visible = 'off';
            app.GS_Aperture_EditField.Editable = 'off';
            
            % Left panel lamps
            app.Auto_Update_Lamp.Color = [1 0 0] ;
            app.Fresnel_lens_Lamp.Color = [1 0 0];
            app.Diffraction_grating_Lamp.Color = [1 0 0];
            app.Optical_vortex_Lamp.Color = [1 0 0];
            app.Bessel_beam_Lamp.Color = [1 0 0];
            app.Aperture_Lamp.Color = [1 0 0];
            app.GS_Lamp.Color = [1 0 0];
            app.Hologram_Lamp.Color = [1 0 0];
            app.Phase_shift_Lamp.Color = [1 0 0];
            app.Airy_beam_Lamp.Color = [1 0 0];
            app.Transformation_Lamp.Color = [1 0 0];
            app.Rotation_Lamp.Color = [1 0 0];
            app.Sinusoidal_grating_Lamp.Color = [1 0 0] ;
            app.Zernike_Lamp.Color = [1 0 0];
            
            % Spinner limits
            app.Number_of_grooves_y_Spinner.Limits = [0 app.Height.Value/2];
            app.Number_of_grooves_x_Spinner.Limits = [0 app.Width.Value/2];
            
            % Defines initial parameters 
            app.phase_map = zeros(app.Height.Value,app.Width.Value);
            app.hologram_3D = zeros(app.Height.Value, app.Width.Value, 9);
            x = -app.Width.Value/2:1:(app.Width.Value/2-1); 
            y = -app.Height.Value/2:1:(app.Height.Value/2-1);
            app.Aperture_Diameter.Value = app.Height.Value;
            app.GS_Aperture_EditField.Value = app.Aperture_Diameter.Value;
            app.Airy_Height_EditField.Value = app.Height.Value;
            app.Airy_Width_EditField.Value = app.Width.Value;
            app.pixel_size = app.PixelsizeEditField.Value^-6;
            app.lambda = app.WavelengthEditField.Value^-9;
            
            % Creates matrices with coordination system
            [app.X, app.Y] = meshgrid(x,y);
            
            
        end

        % Value changed function: Auto_Update_Switch
        function Auto_Update_SwitchValueChanged(app, event)
            
            if strcmp(app.Auto_Update_Switch.Value, 'on')
                app.Start_Button.Enable = 'off' ;
                app.Auto_Update_Lamp.Color = [0 1 0] ;
            else
                app.Start_Button.Enable = 'on' ;
                app.Auto_Update_Lamp.Color = [1 0 0] ;
            end 
            SLM_on_off(app);
            
        end

        % Value changed function: Fresnel_CheckBox
        function Fresnel_CheckBoxValueChanged(app, event)
            
            app.Fresnel_lens_Lamp.Color = [0 1 0] ;
            
            if app.focus.Value ~= 0
                    app.hologram_3D(:,:,1) = fresnel(app);
            else
                    app.hologram_3D(:,:,1) = app.phase_map;
            end
            
            if app.Fresnel_CheckBox.Value == 0
                app.Fresnel_lens_Lamp.Color = [1 0 0] ;
                app.hologram_3D(:,:,1) = app.phase_map;
            end            
            
            SLM_on_off(app);
            
        end

        % Value changed function: Optical_vortex_CheckBox
        function Optical_vortex_CheckBoxValueChanged(app, event)
            
            app.Optical_vortex_Lamp.Color = [0 1 0];
            
            if app.Topological_charge_Spinner.Value ~= 0
                    app.hologram_3D(:,:,2) = vortex(app);
                    app.vortex_copy = vortex(app);
            else
                    app.hologram_3D(:,:,2) = app.phase_map;
                    app.vortex_copy = app.phase_map;
            end
            
            if app.Optical_vortex_CheckBox.Value == 0
                app.Optical_vortex_Lamp.Color = [1 0 0];
                app.hologram_3D(:,:,2) = app.phase_map;
            end
            
            SLM_on_off(app);
            
        end

        % Value changed function: Bessel_beam_CheckBox
        function Bessel_beam_CheckBoxValueChanged(app, event)
           
            app.Bessel_beam_Lamp.Color = [0 1 0];
            
            if app.Topological_charge_Spinner.Value ~= 0
                    app.hologram_3D(:,:,3) = bessel(app);
            else
                    app.hologram_3D(:,:,3) = app.phase_map;
            end
            
            if app.Bessel_beam_CheckBox.Value == 0
                app.Bessel_beam_Lamp.Color = [1 0 0];
                app.hologram_3D(:,:,3) = app.phase_map;
            end
            
            SLM_on_off(app);
            
        end

        % Value changed function: Aperture_CheckBox
        function Aperture_CheckBoxValueChanged(app, event)

            app.Aperture_Lamp.Color = [0 1 0];
            if app.Aperture_CheckBox.Value == 0
                app.Aperture_Lamp.Color = [1 0 0] ;
            end 
            
           SLM_on_off(app);
           
        end

        % Callback function
        function CorrectionCheckBoxValueChanged(app, event)
           
            value = app.CorrectionCheckBox.Value;
            if value == 1
                app.GS_Lamp.Color = [0 1 0] ;
            else
                app.GS_Lamp.Color = [1 0 0] ;
            end 
            
        end

        % Callback function
        function ZernikepolyCheckBoxValueChanged(app, event)
            
            value = app.ZernikepolyCheckBox.Value;
            if value == 1
                app.Zernike_poly_Lamp.Color = [0 1 0] ;
            else
                app.Zernike_poly_Lamp.Color = [1 0 0] ;
            end 
            
        end

        % Value changed function: Start_Button
        function Start_ButtonValueChanged(app, event)
            
            SLM_on_off(app);
            
        end

        % Value changed function: Topological_charge_Spinner
        function Topological_charge_SpinnerValueChanged(app, event)
            
            Optical_vortex_CheckBoxValueChanged(app);
            SLM_on_off(app);
            
        end

        % Value changed function: Axicon_angle_dg_EditField
        function Axicon_angle_dg_EditFieldValueChanged(app, event)
            
            Bessel_beam_CheckBoxValueChanged(app);
            SLM_on_off(app);
            
        end

        % Value changed function: Refractive_index_EditField
        function Refractive_index_EditFieldValueChanged(app, event)
            
            Bessel_beam_CheckBoxValueChanged(app);
            SLM_on_off(app);
            
        end

        % Value changed function: Phase_shift_CheckBox
        function Phase_shift_CheckBoxValueChanged(app, event)
                       
            app.Phase_shift_Lamp.Color = [0 1 0];            
            app.hologram_3D(:,:,4) = phase_rad(app);
            
            if app.Phase_shift_CheckBox.Value == 0
                app.Phase_shift_Lamp.Color = [1 0 0];
                app.hologram_3D(:,:,4) = app.phase_map;
            end
            
            SLM_on_off(app);
            
        end

        % Value changed function: deg_Spinner
        function deg_SpinnerValueChanged(app, event)
           
            rad = phase_rad(app);
            text_rad = string(rad);
            app.Radian.Value = sprintf('%.3f pi', text_rad);
            
            Phase_shift_CheckBoxValueChanged(app);
            SLM_on_off(app);
            
        end

        % Value changed function: Number_of_grooves_y_Spinner
        function Number_of_grooves_y_SpinnerValueChanged(app, event)
            
            vecy = vector(app, app.Height.Value, app.Number_of_grooves_y_Spinner.Value);
            text_vecy = string(vecy);
            app.Vector_Y.Value = sprintf('%f', text_vecy);

            Diffraction_grating_CheckBoxValueChanged(app);
            Sinusoidal_diffraction_grating_CheckBoxValueChanged(app);
            %SLM_on_off(app);
            
        end

        % Value changed function: Number_of_grooves_x_Spinner
        function Number_of_grooves_x_SpinnerValueChanged(app, event)
           
            vecx = vector(app, app.Width.Value, app.Number_of_grooves_x_Spinner.Value);
            text_vecx = string(vecx);
            app.Vector_X.Value = sprintf('%f', text_vecx);             
            
            Diffraction_grating_CheckBoxValueChanged(app);
            Sinusoidal_diffraction_grating_CheckBoxValueChanged(app);
            %SLM_on_off(app);
            
        end

        % Value changed function: Diffraction_grating_CheckBox
        function Diffraction_grating_CheckBoxValueChanged(app, event)
                     
            if app.Diffraction_grating_CheckBox.Value == 1
                app.Sinusoidal_diffraction_grating_CheckBox.Enable = 'off';
            else
                app.Sinusoidal_diffraction_grating_CheckBox.Enable = 'on';
            end
            nx = app.Number_of_grooves_x_Spinner.Value;
            ny = app.Number_of_grooves_y_Spinner.Value;
            app.Diffraction_grating_Lamp.Color = [0 1 0];            
            app.hologram_3D(:,:,5) = diff_grating(app,nx,ny);

            if app.Diffraction_grating_CheckBox.Value == 0
                app.Diffraction_grating_Lamp.Color = [1 0 0];
                app.hologram_3D(:,:,5) = app.phase_map;
            end
            
            SLM_on_off(app);
            
        end

        % Value changed function: Aperture_Diameter
        function Aperture_DiameterValueChanged(app, event)
            SLM_on_off(app);            
        end

        % Button pushed function: Save_Button
        function Save_Button_Pushed(app, event)
            
            %M = app.hologram_sum/max(app.hologram_sum(:)); % Grayscale normalization
            [filename,app.pathname] = uiputfile('Hologram.bmp','Save file name');
            imwrite(mat2gray(app.hologram_sum,[0 2*pi]), [app.pathname filename]);
            figure(app.UIFigure);
            
        end

        % Value changed function: focus
        function focusValueChanged(app, event)
            Fresnel_CheckBoxValueChanged(app);
            SLM_on_off(app);
        end

        % Value changed function: External_Hologram_CheckBox
        function External_Hologram_CheckBoxValueChanged(app, event)
            
            value = app.External_Hologram_CheckBox.Value;
            app.Hologram_Lamp.Color = [0 1 0] ; 
            %app.hologram_3D(:,:,6) = app.external_holo;
            if value == 0
                app.Hologram_Lamp.Color = [1 0 0] ;
                app.hologram_3D(:,:,6) = app.phase_map;
            end 
            SLM_on_off(app);

        end

        % Value changed function: 
        % Sinusoidal_diffraction_grating_CheckBox
        function Sinusoidal_diffraction_grating_CheckBoxValueChanged(app, event)
            
            if app.Sinusoidal_diffraction_grating_CheckBox.Value == 1
                app.Diffraction_grating_CheckBox.Enable = 'off';
            else
                app.Diffraction_grating_CheckBox.Enable = 'on';
            end
            nx = app.Number_of_grooves_x_Spinner.Value;
            ny = app.Number_of_grooves_y_Spinner.Value;
            
            
            if app.Sinusoidal_diffraction_grating_CheckBox.Value == 1
                app.Sinusoidal_grating_Lamp.Color = [0 1 0];
                app.hologram_3D(:,:,5) = sinusoidal_grating(app,nx,ny);
  
            else
                app.Sinusoidal_grating_Lamp.Color = [1 0 0] ;
            end
            
            SLM_on_off(app);

        end

        % Value changed function: Airy_CheckBox
        function Airy_CheckBoxValueChanged(app, event)
            
            app.Airy_beam_Lamp.Color = [0 1 0]; 
            app.hologram_3D(:,:,8) = airy(app);
            
            if app.Airy_CheckBox.Value == 0
                app.Airy_beam_Lamp.Color = [1 0 0];
                app.hologram_3D(:,:,8) = app.phase_map;
            end
           
            SLM_on_off(app);
        end

        % Button pushed function: Load_hologram_Button
        function Load_hologram_ButtonPushed(app, event)

            [filename, app.pathname] = uigetfile({'*.jpg;*.bmp;*.png;','Graphics (*.jpg,*.bmp,*.png)'},'File Selector', 'MultiSelect','on');
            if ischar(filename) == 1
                [~, N] = size({filename});
            else
                [~, N] = size(filename);
            end           
            app.NH = app.NH+N;
            app.List_Box.Items(app.i:1:app.NH) = cellstr(filename);   
            %app.List_Box.Items = cellstr(filename);
            app.i = app.i+N;
            figure(app.UIFigure);
            
        end

        % Value changed function: List_Box
        function List_BoxValueChanged(app, event)
            
            app.external_holo=im2double(imread([app.pathname app.List_Box.Value]))*2*pi;
            
            % Verify if its an RGB image
            if_rgb=ismember(3,size(app.external_holo));    
            
            % Load the image
                if if_rgb==1 
                      app.external_holo=im2double(rgb2gray(imread([app.pathname app.List_Box.Value])))*2*pi;
                end
            % Change Hologram if difrrent is checked 
             if app.External_Hologram_CheckBox.Value == 1
                 app.hologram_3D(:,:,6) = app.external_holo;
             end
            SLM_on_off(app);
            %app.Hologram.Visible = 'on';
            imshow(app.external_holo,[],'Parent',app.Hologram);
            
        end

        % Value changed function: Translation_x_Spinner
        function Translation_x_SpinnerValueChanged(app, event)
            
            app.hologram_3D(:,:,2) = app.vortex_copy;
            Translation_Rotation_CheckBoxValueChanged(app);
            SLM_on_off(app);
            
        end

        % Value changed function: Translation_y_Spinner
        function Translation_y_SpinnerValueChanged(app, event)

            app.hologram_3D(:,:,2) = app.vortex_copy;
            Translation_Rotation_CheckBoxValueChanged(app);
            SLM_on_off(app);
            
        end

        % Value changed function: Rotation_Spinner
        function Rotation_SpinnerValueChanged(app, event)

            app.hologram_3D(:,:,2) = app.vortex_copy;
            Translation_Rotation_CheckBoxValueChanged(app);
            SLM_on_off(app);
            
        end

        % Value changed function: Translation_Rotation_CheckBox
        function Translation_Rotation_CheckBoxValueChanged(app, event)
            
            if app.Translation_x_Spinner.Value ~= 0 || app.Translation_x_Spinner.Value ~= 0
                app.Transformation_Lamp.Color = [0 1 0];
                
            end
            if app.Rotation_Spinner.Value ~= 0 
                app.Rotation_Lamp.Color = [0 1 0];
            end
            if app.Translation_Rotation_CheckBox.Value == 0
                app.Transformation_Lamp.Color = [1 0 0];
                app.Rotation_Lamp.Color = [1 0 0];
            end
            
            SLM_on_off(app);
            
        end

        % Callback function
        function GS_number_of_grooves_y_SpinnerValueChanged(app, event)

            app.vecy = vector(app, app.Height.Value, app.GS_number_of_grooves_y_Spinner.Value);
            GS_text_vecy = string(app.vecy);
            app.GS_Vector_Y.Value = sprintf('%f', GS_text_vecy);
            app.Vector_Y.Value = sprintf('%f', GS_text_vecy);
            
            GS_CheckBoxValueChanged(app);
            SLM_on_off(app);
            
        end

        % Callback function
        function GS_CheckBoxValueChanged(app, event)
            
            app.GS_Lamp.Color = [0 1 0];            
            
            if app.GS_CheckBox.Value == 0
                app.GS_Lamp.Color = [1 0 0];
            end
            
            SLM_on_off(app);
            
        end

        % Button pushed function: First_grating_Button
        function First_grating_ButtonPushed(app, event)
            
            switch app.x_Button.Value
                case 1
                    app.Number_of_grooves_1st_EditField.Value = sprintf('%.f', string(app.Number_of_grooves_x_Spinner.Value));
                    app.Grating_vector_1st_EditField.Value = sprintf('%f', string(app.Vector_X.Value));
                    
                    %app.n_1st = app.Number_of_grooves_x_1st_EditField.Value;
                otherwise
                    app.Number_of_grooves_1st_EditField.Value = sprintf('%.f', string(app.Number_of_grooves_y_Spinner.Value));
                    app.Grating_vector_1st_EditField.Value = sprintf('%f', string(app.Vector_Y.Value));
                    
                    %app.n_1st = app.Number_of_grooves_y_1st.EditField.Value;
            end
            
        end

        % Button pushed function: Second_grating_Button
        function Second_grating_ButtonPushed(app, event)
           
            switch app.x_Button.Value
                case 1
                    app.Number_of_grooves_2nd_EditField.Value = sprintf('%.f', string(app.Number_of_grooves_x_Spinner.Value));
                    app.Grating_vector_2nd_EditField.Value = sprintf('%f', string(app.Vector_X.Value));
                    
                    %app.n_2nd = app.Number_of_grooves_x_2nd_EditField.Value;
                otherwise
                    app.Number_of_grooves_2nd_EditField.Value = sprintf('%.f', string(app.Number_of_grooves_y_Spinner.Value));
                    app.Grating_vector_2nd_EditField.Value = sprintf('%f', string(app.Vector_Y.Value));
                    
                    %app.n_2nd = app.Number_of_grooves_y_2nd_EditField.Value;
            end
            
        end

        % Selection changed function: Aperture_size_ButtonGroup
        function Aperture_size_ButtonGroupSelectionChanged(app, event)

            if app.Original_Button.Value == 1
                app.GS_Aperture_EditField.Value = app.Aperture_Diameter.Value;
                app.GS_Aperture_EditField.Editable = 'off';
                
            elseif app.Half_Button.Value == 1
                app.GS_Aperture_EditField.Value = app.Aperture_Diameter.Value/2;
                app.GS_Aperture_EditField.Editable = 'off';
                
            elseif app.Quarter_Button.Value == 1
                app.GS_Aperture_EditField.Value = app.Aperture_Diameter.Value/4;
                app.GS_Aperture_EditField.Editable = 'off';
                
            else
                app.GS_Aperture_EditField.Value = 0;
                app.GS_Aperture_EditField.Editable = 'on';
            end
            
        end

        % Button pushed function: Set_up_values_Button
        function Set_up_values_ButtonPushed(app, event)
            
            app.grating_vector_diff = abs(str2double(app.Grating_vector_2nd_EditField.Value) - str2double(app.Grating_vector_1st_EditField.Value));
            
            switch app.x_Button.Value
                case 1
                    app.n_1st = str2double(app.Number_of_grooves_1st_EditField.Value);
                    app.n_2nd = str2double(app.Number_of_grooves_2nd_EditField.Value);
                    %Display 1st diffraction grating
                    mapa = diff_grating(app,app.n_1st,0);
                    mapa=cover_with_aperture(app,mapa,app.Aperture_Diameter.Value);
                    [~, col] = get_position(app,app.vid, mapa, app.hologram_sum);  
                    position_1 = col;
                    
                    %Display 2st diffraction grating
                    mapa = diff_grating(app,app.n_2nd,0);
                    mapa=cover_with_aperture(app,mapa,app.Aperture_Diameter.Value);
                    [~, col] = get_position(app,app.vid, mapa, app.hologram_sum);  
                    position_2 = col;
                    
                    app.position_diff = abs(position_2-position_1);
                    
                otherwise
                    
                    %Display 1st diffraction grating
                    mapa = diff_grating(app,app.n_1st,0);
                    mapa=cover_with_aperture(app,mapa,app.GS_Aperture_EditField.Value);
                    [row, ~] = get_position(app,app.vid, mapa, app.hologram_sum);  
                    position_1 = row;
                    
                    %Display 2st diffraction grating
                    mapa = diff_grating(app,app.n_2nd,0);
                    mapa=cover_with_aperture(app,mapa,app.GS_Aperture_EditField.Value);
                    [row, ~] = get_position(app,app.vid, mapa, app.hologram_sum);  
                    position_2 = row;
                    
                    app.position_diff = abs(position_2-position_1);
                    
            end                
                figure(app.UIFigure);
                
                %Calculate padding value
                
                app.padding=calculate_padding(app,app.GS_Aperture_EditField.Value, app.grating_vector_diff, app.position_diff, app.Aperture_Diameter.Value);
                
                %Round to nearest even integer
                app.padding=round((app.padding-2)/2)*2+2;
                
                %Set padding value
                app.Padding_EditField.Value = sprintf('%.f', string(app.padding));
                
        end

        % Button pushed function: GS_Button
        function GS_ButtonPushed(app, event)
            
            app.Current_iteration_number_EditField.BackgroundColor = [0.96 0.96 0.96];
            app.Current_iteration_number_EditField.Value = 0;
           %w2 = app.Width.Value;
           % h2 = app.Height.Value;
            aperture_diameter = app.GS_Aperture_EditField.Value;
            padsize = app.padding;
            w = padsize;
            h = padsize;
            
            % Perfect vortex
            x = -w/2:1:(w/2-1); y = -h/2:1:(h/2-1);
            [X1, Y1] = meshgrid(x,y);
            phi = angle(X1+1i*Y1);
            vortex = mod(app.Topological_charge_Spinner.Value*phi,2*pi);
            
            % Experimental source image of focused vortex
            [row, col] = get_position_2(app,app.vid);
            app.half_side = round(abs(col(2)-col(1)));
            app.center = [row(1), col(1)];
            
            try
                source = im_crop(app,getsnapshot(app.vid),app.center,app.half_side);
            catch
                 disp('No camera detected')
            end
            
            [sizey, sizex] = size(source);
            
            % Zero padding of experimental source image
            source = padarray(source,round([(str2double(app.Padding_EditField.Value)-sizey)/2 (str2double(app.Padding_EditField.Value)-sizex)/2]));
            amp = aperture(app,ones(app.padding,app.padding),aperture_diameter,app.padding,app.padding);
            
            % G-S algorithm
            H = vortex;
            E = amp.*exp(1i.*H);
            E_FT = fftshift(fft2(E));
            I = abs(E_FT).^2;
            clear phi l app.X app.Y x y
            source=mat2gray(source);
            
            for j=1:1:app.Iterations_EditField.Value
                app.Current_iteration_number_EditField.Value = j;
                E = amp.*exp(1i.*H);
                E_FT = fftshift(fft2(E));
                
                H = angle(E_FT)+pi;
                
                E2 = source.*exp(1i.*H);
                E2 = ifft2(ifftshift(E2));
                
                H = angle(E2)+pi;
                
                if j==app.Iterations_EditField.Value
                       app.Current_iteration_number_EditField.BackgroundColor = [0.83 0.93 1];
                end
            end
            
            I = abs(E_FT).^2;
            clear E E2 amp
            
            % Preparing correction map
            C = mod(H-vortex, 2*pi);
            C = aperture(app,C,aperture_diameter,padsize,padsize);
           
            clear H

            C = imresize(C,convergent(app.Aperture_Diameter.Value/aperture_diameter),'nearest');
            [p3, p4] = size(C);
            q1 = app.Height.Value - 1;
            q2 = app.Width.Value - 1;
            i3_start = floor((p3-q1)/2);
            i3_stop = i3_start + q1;
            
            i4_start = floor((p4-q2)/2);
            i4_stop = i4_start + q2;
            
            C = C(i3_start:i3_stop, i4_start:i4_stop);
            C = aperture(app, C, app.Aperture_Diameter.Value,app.Width.Value,app.Height.Value);
            %C = imcrop(C, [(padsize/2-w2/2) (padsize/2-h2/2) w2-1 h2-1]);
            app.correction_value = C;
            
            % Display final map
            imshow(C, [], 'Parent',app.Correction);
            clear source
            %%app.GS_Button.BackgroundColor = [0.94,0.94,0.94];
            
        end

        % Button pushed function: Snapshot_Button
        function Snapshot_ButtonPushed(app, event)
          %Select_a_camera_DropDownValueChanged(app);
            try
            img = getsnapshot(app.vid);
            
            if length(size(img))~= 2
                img= im2double(rgb2gray(img));
            end
            
            [filename,app.pathname] = uiputfile('snap.bmp','Save file name');
            imwrite(mat2gray(img), [app.pathname filename]);
            figure(app.UIFigure);
            catch
            disp('No camera detected')    
            end
        end

        % Value changed function: Select_a_camera_DropDown
        function Select_a_camera_DropDownValueChanged(app, event)
            
            try
                closepreview(app.vid); 
            catch     
            end
            
            try            
            index = find(strcmp(app.names_cellstr, app.Select_a_camera_DropDown.Value))-1;
            
            dev_info = imaqhwinfo(app.adaptor, index);
            format = dev_info.DefaultFormat;
            app.vid = videoinput(app.adaptor,app.Select_a_camera_DropDown.Value,format);
            %src = getselectedsource(app.vid);
            app.vid.FramesPerTrigger = 1;
            catch
            disp('No camera detected')
            end
            
            %Check if it's possible to change the colorspace to RGB
            try
                app.vid.ReturnedColorspace = 'rgb';
            catch
            end
            preview(app.vid);
            
            %Set parameters
            src=getselectedsource(app.vid);
            app.brightness=src.Brightness;
            app.contrast=src.Contrast;
            app.exposure=src.Exposure;
            app.gain=src.Gain;
            
            
            %{
            app.Contrasts=app.vid.Contrast;
            app.Expsoure=app.vid.Expsoure;
            %}
            
        end

        % Value changed function: Airy_Width_EditField
        function Airy_Width_EditFieldValueChanged(app, event)
            
            Airy_CheckBoxValueChanged(app);
            SLM_on_off(app);
            
        end

        % Value changed function: Airy_Height_EditField
        function Airy_Height_EditFieldValueChanged(app, event)
            
            Airy_CheckBoxValueChanged(app);
            SLM_on_off(app);
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            close(app.fig);
            delete(app.DialogApp)
            delete(app);
            
            
        end

        % Callback function
        function Beam_waist_EditFieldValueChanged(app, event)
            
            Amplitude_modulation_CheckBoxValueChanged(app);
            SLM_on_off(app);
            
        end

        % Value changed function: Iterations_EditField
        function Iterations_EditFieldValueChanged(app, event)
          
            % GS_ButtonPushed(app);
            SLM_on_off(app);
            
        end

        % Value changed function: On_off_correction
        function On_off_correctionValueChanged(app, event)
            
            value = app.On_off_correction.Value;
            if value == 1
                app.GS_Lamp.Color = [0 1 0] ;
            else
                app.GS_Lamp.Color = [1 0 0] ;
            end 
            SLM_on_off(app);
            
        end

        % Value changed function: On_off_Zernike
        function On_off_ZernikeValueChanged(app, event)
            
            if app.On_off_Zernike.Value ==1
                app.hologram_3D(:,:,9) = app.phase_map;
                values = [app.Piston_EditField.Value, app.Y_Tilt_EditField.Value, app.X_Tilt_EditField.Value, app.Oblique_astgm_EditField.Value, ...
                     app.Defocuse_EditField.Value, app.Vertical_EditField.Value, app.Vertical_trefoil_EditField.Value + app.Vertical_coma_EditField.Value, ...
                     app.Horizontal_coma_EditField.Value, app.Horizontal_trefoil_EditField.Value];
                num_of_elements = length(values);
                tab = [0, -1, 1, -2, 0, 2, -3, -1, 1, 3; 0, 1, 1, 2, 2, 2, 3, 3, 3, 3];
                for i_z=1:num_of_elements
                    app.hologram_3D(:,:,9) = app.hologram_3D(:,:,9)+do_zernike(app, app.Width, app.Height, tab(1,i_z), tab(2,i_z), values(i_z));
                end
                app.Zernike_Lamp.Color = [0 1 0] ;
            else
                app.hologram_3D(:,:,9) = app.phase_map;
                app.Zernike_Lamp.Color = [1 0 0] ;
            end
            
            SLM_on_off(app);
            
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            
%             [filename, app.pathname] = uigetfile({'*.jpg;*.bmp;*.png;','Graphics (*.jpg,*.bmp,*.png)'},'File Selector', 'MultiSelect','on');
%             %app.List_Box.Items(app.i) = cellstr(filename);
              L = length(app.List_Box.Items);
              for L0 = 1:1:L
                  app.List_Box.Items(L0) = cellstr({''});
                  %delete(app.List_Box.ItemsData(L0));
                  %clear(app.List_Box.Value(L0));
              end
              imshow(app.phase_map,[],'Parent',app.Hologram);
              app.Hologram.Visible = 'off';
              %delete(app.List_Box.ItemsData);
%             %app.i = app.i+1;
%             figure(app.UIFigure);
              app.NH = 0;
              app.i = 1;
            SLM_on_off(app);
            
        end

        % Button pushed function: Create_Button
        function Create_ButtonPushed(app, event)
         
            Screen_positions = get(0,'MonitorPositions');
            No_of_screens = size(Screen_positions,1);
            
            if No_of_screens == 1
                
                % SLM not detected
                app.SLM_Lamp.Color = [0 0 0];
                
                % Defines default coordination system vectors
                app.Width.Value = 1920;       % Resolution x
                app.Height.Value = 1080;      % Resolution y
                app.SLM_Detected_EditField.Value = "Not detected";
                
            else
                
                % SLM detected
                app.SLM_Lamp.Color = [0 1 0];
                app.SLM_resolution = Screen_positions(2,:);
                app.Width.Value = app.SLM_resolution(3);
                app.Height.Value = app.SLM_resolution(4);
                app.fig=figure('Name','SLM','Position',app.SLM_resolution,'MenuBar','none', 'ToolBar', 'none', 'WindowState', 'fullscreen', 'WindowStyle',"modal");
                app.SLM_axes = axes('Parent',app.fig,'Units','pixels','Position',[0 0 app.SLM_resolution(3) app.SLM_resolution(4)]);
                app.SLM_Detected_EditField.Value = "Detected";
            
            end
            
            app.phase_map = zeros(app.Height.Value,app.Width.Value);
            app.hologram_3D = zeros(app.Height.Value, app.Width.Value, 9);
            x = -app.Width.Value/2:1:(app.Width.Value/2-1); 
            y = -app.Height.Value/2:1:(app.Height.Value/2-1);
           
            % Creates matrices with coordination system
            [app.X, app.Y] = meshgrid(x,y);
            
        end

        % Value changed function: Y_Tilt_EditField
        function Y_Tilt_EditFieldValueChanged(app, event)

            On_off_ZernikeValueChanged(app);
            SLM_on_off(app);
            
        end

        % Value changed function: Piston_EditField
        function Piston_EditFieldValueChanged(app, event)

            On_off_ZernikeValueChanged(app);
            SLM_on_off(app);
            
        end

        % Value changed function: X_Tilt_EditField
        function X_Tilt_EditFieldValueChanged(app, event)

            On_off_ZernikeValueChanged(app);
            SLM_on_off(app);
            
        end

        % Value changed function: Oblique_astgm_EditField
        function Oblique_astgm_EditFieldValueChanged(app, event)

            On_off_ZernikeValueChanged(app);
            SLM_on_off(app);
            
        end

        % Value changed function: Defocuse_EditField
        function Defocuse_EditFieldValueChanged(app, event)

            On_off_ZernikeValueChanged(app);
            SLM_on_off(app);
            
        end

        % Value changed function: Vertical_EditField
        function Vertical_EditFieldValueChanged(app, event)

            On_off_ZernikeValueChanged(app);
            SLM_on_off(app);
            
        end

        % Value changed function: Vertical_trefoil_EditField
        function Vertical_trefoil_EditFieldValueChanged(app, event)

            On_off_ZernikeValueChanged(app);
            SLM_on_off(app);
            
        end

        % Value changed function: Vertical_coma_EditField
        function Vertical_coma_EditFieldValueChanged(app, event)

            On_off_ZernikeValueChanged(app);
            SLM_on_off(app);
            
        end

        % Value changed function: Horizontal_coma_EditField
        function Horizontal_coma_EditFieldValueChanged(app, event)

            On_off_ZernikeValueChanged(app);
            SLM_on_off(app);
            
        end

        % Value changed function: Horizontal_trefoil_EditField
        function Horizontal_trefoil_EditFieldValueChanged(app, event)

            On_off_ZernikeValueChanged(app);
            SLM_on_off(app);
            
        end

        % Value changed function: VortexonlySwitch
        function VortexonlySwitchValueChanged(app, event)
            value = app.VortexonlySwitch.Value;
            
        end

        % Value changed function: Width
        function WidthValueChanged(app, event)
            app.phase_map = zeros(app.Height.Value,app.Width.Value);
            app.hologram_3D = zeros(app.Height.Value, app.Width.Value, 9);
            x = -app.Width.Value/2:1:(app.Width.Value/2-1); 
            y = -app.Height.Value/2:1:(app.Height.Value/2-1);
           
            % Creates matrices with coordination system
            [app.X, app.Y] = meshgrid(x,y);
        end

        % Value changed function: Height
        function HeightValueChanged(app, event)
            app.phase_map = zeros(app.Height.Value,app.Width.Value);
            app.hologram_3D = zeros(app.Height.Value, app.Width.Value, 9);
            x = -app.Width.Value/2:1:(app.Width.Value/2-1); 
            y = -app.Height.Value/2:1:(app.Height.Value/2-1);
           
            % Creates matrices with coordination system
            [app.X, app.Y] = meshgrid(x,y);
        end

        % Value changed function: WavelengthEditField
        function WavelengthEditFieldValueChanged(app, event)
            app.lambda = app.WavelengthEditField.Value*1e-9;
            
            Fresnel_CheckBoxValueChanged(app)
            Bessel_beam_CheckBoxValueChanged(app)
            SLM_on_off(app)
        end

        % Value changed function: PixelsizeEditField
        function PixelsizeEditFieldValueChanged(app, event)
            app.pixel_size = app.PixelsizeEditField.Value*1e-6;
            
            Fresnel_CheckBoxValueChanged(app)
            Bessel_beam_CheckBoxValueChanged(app)
            SLM_on_off(app)
        end

        % Value changed function: options
        function optionsValueChanged(app, event)
                        
            % Disable Options button while dialog is open
            app.options.Enable = 'off';
            
            % Open the options dialog and pass inputs
            %app.DialogApp = CameraSettings(app, app.CurrentSize, app.CurrentColormap);
            app.DialogApp = CameraSettings(app, app.brightness, app.contrast, app.exposure, app.gain, getselectedsource(app.vid));
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {746, 746};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {587, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SLM_Screen = uiaxes(app.LeftPanel);
            title(app.SLM_Screen, '')
            xlabel(app.SLM_Screen, '')
            ylabel(app.SLM_Screen, '')
            app.AutoUpdateSwitchLabel = uilabel(app.LeftPanel);
            app.AutoUpdateSwitchLabel.Text = 'Auto Update';
            app.Auto_Update_Switch = uiswitch(app.LeftPanel, 'slider');
            app.Auto_Update_Switch.Items = {'off', 'on'};
            app.Auto_Update_Switch.ValueChangedFcn = createCallbackFcn(app, @Auto_Update_SwitchValueChanged, true);
            app.Auto_Update_Switch.Value = 'off';
            app.Auto_Update_Lamp = uilamp(app.LeftPanel);
            app.SLMScreenLabel_2 = uilabel(app.LeftPanel);
            app.SLMScreenLabel_2.Text = 'SLM Screen';
            app.FresnellensLampLabel = uilabel(app.LeftPanel);
            app.FresnellensLampLabel.Text = 'Fresnel lens';
            app.Fresnel_lens_Lamp = uilamp(app.LeftPanel);
            app.BlazedgratingLabel_2 = uilabel(app.LeftPanel);
            app.BlazedgratingLabel_2.Text = 'Blazed grating';
            app.Diffraction_grating_Lamp = uilamp(app.LeftPanel);
            app.OpticalvortexLampLabel = uilabel(app.LeftPanel);
            app.OpticalvortexLampLabel.Text = 'Optical vortex';
            app.Optical_vortex_Lamp = uilamp(app.LeftPanel);
            app.BesselbeamLampLabel = uilabel(app.LeftPanel);
            app.BesselbeamLampLabel.Text = 'Bessel beam';
            app.Bessel_beam_Lamp = uilamp(app.LeftPanel);
            app.ApertureLampLabel = uilabel(app.LeftPanel);
            app.ApertureLampLabel.Text = 'Aperture';
            app.Aperture_Lamp = uilamp(app.LeftPanel);
            app.GerchbergSaxtonLabel = uilabel(app.LeftPanel);
            app.GerchbergSaxtonLabel.Text = 'Gerchberg – Saxton';
            app.GS_Lamp = uilamp(app.LeftPanel);
            app.ExternalhologramLabel = uilabel(app.LeftPanel);
            app.ExternalhologramLabel.Text = 'External hologram';
            app.Hologram_Lamp = uilamp(app.LeftPanel);
            app.Start_Button = uibutton(app.LeftPanel, 'state');
            app.Start_Button.ValueChangedFcn = createCallbackFcn(app, @Start_ButtonValueChanged, true);
            app.Start_Button.Text = {'Start'; ''};
            app.PhaseshiftLampLabel = uilabel(app.LeftPanel);
            app.PhaseshiftLampLabel.Text = 'Phase shift';
            app.Phase_shift_Lamp = uilamp(app.LeftPanel);
            app.Width = uieditfield(app.LeftPanel, 'numeric');
            app.Width.Limits = [0 Inf];
            app.Width.ValueChangedFcn = createCallbackFcn(app, @WidthValueChanged, true);
            app.Width.Value = 1920;
            app.Height = uieditfield(app.LeftPanel, 'numeric');
            app.Height.Limits = [0 Inf];
            app.Height.ValueChangedFcn = createCallbackFcn(app, @HeightValueChanged, true);
            app.Height.Value = 1080;
            app.Save_Button = uibutton(app.LeftPanel, 'push');
            app.Save_Button.ButtonPushedFcn = createCallbackFcn(app, @Save_Button_Pushed, true);
            app.Save_Button.Text = 'Save';
            app.XLabel = uilabel(app.LeftPanel);
            app.XLabel.Text = 'X';
            app.AirybeamLampLabel = uilabel(app.LeftPanel);
            app.AirybeamLampLabel.Text = 'Airy beam';
            app.Airy_beam_Lamp = uilamp(app.LeftPanel);
            app.TransformationLampLabel = uilabel(app.LeftPanel);
            app.TransformationLampLabel.Text = 'Transformation';
            app.Transformation_Lamp = uilamp(app.LeftPanel);
            app.RotationLampLabel = uilabel(app.LeftPanel);
            app.RotationLampLabel.Text = 'Rotation';
            app.Rotation_Lamp = uilamp(app.LeftPanel);
            app.PhaseShapingLabel = uilabel(app.LeftPanel);
            app.PhaseShapingLabel.Text = 'Phase Shaping:';
            app.SaveHologramLabel = uilabel(app.LeftPanel);
            app.SaveHologramLabel.Text = 'Save Hologram';
            app.MakeHologramLabel = uilabel(app.LeftPanel);
            app.MakeHologramLabel.Text = 'Make Hologram';
            app.TransformationLabel = uilabel(app.LeftPanel);
            app.TransformationLabel.Text = {'Transformation:'; ''};
            app.CorrectionLabel = uilabel(app.LeftPanel);
            app.CorrectionLabel.Text = 'Correction:';
            app.SinusoidalgratingLabel_2 = uilabel(app.LeftPanel);
            app.SinusoidalgratingLabel_2.Text = 'Sinusoidal grating ';
            app.Sinusoidal_grating_Lamp = uilamp(app.LeftPanel);
            app.ExternalHologramLabel = uilabel(app.LeftPanel);
            app.ExternalHologramLabel.Text = 'External Hologram';
            app.Snapshot_Button = uibutton(app.LeftPanel, 'push');
            app.Snapshot_Button.ButtonPushedFcn = createCallbackFcn(app, @Snapshot_ButtonPushed, true);
            app.Snapshot_Button.Text = {'Snapshot'; ''};
            app.SLM_Lamp = uilamp(app.LeftPanel);
            app.SLMResolutionLabel = uilabel(app.LeftPanel);
            app.SLMResolutionLabel.Text = 'SLM Resolution';
            app.Make_Snap = uilabel(app.LeftPanel);
            app.Make_Snap.Text = 'Get Snapshot';
            app.SelectacameraDropDownLabel = uilabel(app.LeftPanel);
            app.SelectacameraDropDownLabel.Text = 'Select a camera';
            app.Select_a_camera_DropDown = uidropdown(app.LeftPanel);
            app.Select_a_camera_DropDown.Items = {'', ''};
            app.Select_a_camera_DropDown.Editable = 'on';
            app.Select_a_camera_DropDown.ValueChangedFcn = createCallbackFcn(app, @Select_a_camera_DropDownValueChanged, true);
            app.Select_a_camera_DropDown.Value = '';
            app.Create_Button = uibutton(app.LeftPanel, 'push');
            app.Create_Button.ButtonPushedFcn = createCallbackFcn(app, @Create_ButtonPushed, true);
            app.Create_Button.Text = 'Create';
            app.ZernikepolynomialsLabel = uilabel(app.LeftPanel);
            app.ZernikepolynomialsLabel.Text = 'Zernike polynomials';
            app.Zernike_Lamp = uilamp(app.LeftPanel);
            app.SLM_Detected_EditField = uieditfield(app.LeftPanel, 'text');
            app.SLM_Detected_EditField.Editable = 'off';
            app.options = uibutton(app.LeftPanel, 'state');
            app.options.ValueChangedFcn = createCallbackFcn(app, @optionsValueChanged, true);
            app.options.Text = 'Options';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.External_hologram_Tab = uitab(app.TabGroup);
            app.External_hologram_Tab.Title = 'External hologram';
            app.Hologram = uiaxes(app.External_hologram_Tab);
            title(app.Hologram, '')
            xlabel(app.Hologram, '')
            ylabel(app.Hologram, '')
            app.Hologram_Label = uilabel(app.External_hologram_Tab);
            app.Hologram_Label.Text = 'Hologram';
            app.LoadhologramsPanel = uipanel(app.External_hologram_Tab);
            app.LoadhologramsPanel.Title = 'Load holograms';
            app.List_Box = uilistbox(app.LoadhologramsPanel);
            app.List_Box.Items = {};
            app.List_Box.ValueChangedFcn = createCallbackFcn(app, @List_BoxValueChanged, true);
            app.List_Box.Value = {};
            app.External_Hologram_CheckBox = uicheckbox(app.LoadhologramsPanel);
            app.External_Hologram_CheckBox.ValueChangedFcn = createCallbackFcn(app, @External_Hologram_CheckBoxValueChanged, true);
            app.External_Hologram_CheckBox.Text = '   On / Off';
            app.Load_hologram_Button = uibutton(app.LoadhologramsPanel, 'push');
            app.Load_hologram_Button.ButtonPushedFcn = createCallbackFcn(app, @Load_hologram_ButtonPushed, true);
            app.Load_hologram_Button.Text = 'Load hologram';
            app.AddtoSLMLabel = uilabel(app.LoadhologramsPanel);
            app.AddtoSLMLabel.Text = 'Add to SLM:';
            app.ClearButton = uibutton(app.LoadhologramsPanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.Phase_shaping_Tab = uitab(app.TabGroup);
            app.Phase_shaping_Tab.Title = 'Phase shaping and Transformation';
            app.Fresnel_lens_Panel = uipanel(app.Phase_shaping_Tab);
            app.Fresnel_lens_Panel.Title = 'Fresnel lens';
            app.Fresnel_CheckBox = uicheckbox(app.Fresnel_lens_Panel);
            app.Fresnel_CheckBox.ValueChangedFcn = createCallbackFcn(app, @Fresnel_CheckBoxValueChanged, true);
            app.Fresnel_CheckBox.Text = '   On / Off';
            app.FocusmSpinnerLabel = uilabel(app.Fresnel_lens_Panel);
            app.FocusmSpinnerLabel.Text = 'Focus [m]';
            app.focus = uispinner(app.Fresnel_lens_Panel);
            app.focus.Step = 0.25;
            app.focus.Limits = [0 Inf];
            app.focus.ValueChangedFcn = createCallbackFcn(app, @focusValueChanged, true);
            app.focus.Value = 0.5;
            app.Optical_vortex_Panel = uipanel(app.Phase_shaping_Tab);
            app.Optical_vortex_Panel.Title = 'Optical vortex';
            app.Optical_vortex_CheckBox = uicheckbox(app.Optical_vortex_Panel);
            app.Optical_vortex_CheckBox.ValueChangedFcn = createCallbackFcn(app, @Optical_vortex_CheckBoxValueChanged, true);
            app.Optical_vortex_CheckBox.Text = '   On / Off';
            app.TopologicalchargeSpinnerLabel = uilabel(app.Optical_vortex_Panel);
            app.TopologicalchargeSpinnerLabel.Text = 'Topological charge';
            app.Topological_charge_Spinner = uispinner(app.Optical_vortex_Panel);
            app.Topological_charge_Spinner.ValueChangedFcn = createCallbackFcn(app, @Topological_charge_SpinnerValueChanged, true);
            app.Topological_charge_Spinner.Value = 1;
            app.Bessel_beam_Panel = uipanel(app.Phase_shaping_Tab);
            app.Bessel_beam_Panel.Title = 'Axicon';
            app.Bessel_beam_CheckBox = uicheckbox(app.Bessel_beam_Panel);
            app.Bessel_beam_CheckBox.ValueChangedFcn = createCallbackFcn(app, @Bessel_beam_CheckBoxValueChanged, true);
            app.Bessel_beam_CheckBox.Text = '   On / Off';
            app.RefractiveindexLabel = uilabel(app.Bessel_beam_Panel);
            app.RefractiveindexLabel.Text = 'Refractive index';
            app.Refractive_index_EditField = uieditfield(app.Bessel_beam_Panel, 'numeric');
            app.Refractive_index_EditField.ValueChangedFcn = createCallbackFcn(app, @Refractive_index_EditFieldValueChanged, true);
            app.Refractive_index_EditField.Value = 1.5;
            app.ApexangledgLabel = uilabel(app.Bessel_beam_Panel);
            app.ApexangledgLabel.Text = 'Apex angle [dg]';
            app.Axicon_angle_dg_EditField = uieditfield(app.Bessel_beam_Panel, 'numeric');
            app.Axicon_angle_dg_EditField.ValueChangedFcn = createCallbackFcn(app, @Axicon_angle_dg_EditFieldValueChanged, true);
            app.Axicon_angle_dg_EditField.Value = 0.2;
            app.Phase_shift_Panel = uipanel(app.Phase_shaping_Tab);
            app.Phase_shift_Panel.Title = 'Phase shift';
            app.Phase_shift_CheckBox = uicheckbox(app.Phase_shift_Panel);
            app.Phase_shift_CheckBox.ValueChangedFcn = createCallbackFcn(app, @Phase_shift_CheckBoxValueChanged, true);
            app.Phase_shift_CheckBox.Text = '   On / Off';
            app.degSpinnerLabel = uilabel(app.Phase_shift_Panel);
            app.degSpinnerLabel.Text = 'deg';
            app.deg_Spinner = uispinner(app.Phase_shift_Panel);
            app.deg_Spinner.Limits = [0 360];
            app.deg_Spinner.ValueChangedFcn = createCallbackFcn(app, @deg_SpinnerValueChanged, true);
            app.Radian = uieditfield(app.Phase_shift_Panel, 'text');
            app.Radian.Editable = 'off';
            app.radLabel = uilabel(app.Phase_shift_Panel);
            app.radLabel.Text = 'rad';
            app.Diffractiongrating_Panel = uipanel(app.Phase_shaping_Tab);
            app.Diffractiongrating_Panel.Title = 'Blazed and sinusoidal grating';
            app.NumberofgroovesyLabel = uilabel(app.Diffractiongrating_Panel);
            app.NumberofgroovesyLabel.Text = 'Number of grooves y';
            app.Number_of_grooves_y_Spinner = uispinner(app.Diffractiongrating_Panel);
            app.Number_of_grooves_y_Spinner.Step = 2;
            app.Number_of_grooves_y_Spinner.Limits = [0 512];
            app.Number_of_grooves_y_Spinner.ValueChangedFcn = createCallbackFcn(app, @Number_of_grooves_y_SpinnerValueChanged, true);
            app.Grating_vector_x_Label = uilabel(app.Diffractiongrating_Panel);
            app.Grating_vector_x_Label.Text = 'Grating vector y [1/px]';
            app.Vector_Y = uieditfield(app.Diffractiongrating_Panel, 'text');
            app.Vector_Y.Editable = 'off';
            app.Diffraction_grating_CheckBox = uicheckbox(app.Diffractiongrating_Panel);
            app.Diffraction_grating_CheckBox.ValueChangedFcn = createCallbackFcn(app, @Diffraction_grating_CheckBoxValueChanged, true);
            app.Diffraction_grating_CheckBox.Text = '   On / Off';
            app.NumberofgroovesxLabel = uilabel(app.Diffractiongrating_Panel);
            app.NumberofgroovesxLabel.Text = 'Number of grooves x';
            app.Number_of_grooves_x_Spinner = uispinner(app.Diffractiongrating_Panel);
            app.Number_of_grooves_x_Spinner.Step = 2;
            app.Number_of_grooves_x_Spinner.Limits = [0 Inf];
            app.Number_of_grooves_x_Spinner.ValueChangedFcn = createCallbackFcn(app, @Number_of_grooves_x_SpinnerValueChanged, true);
            app.Grating_vector_y_Label = uilabel(app.Diffractiongrating_Panel);
            app.Grating_vector_y_Label.Text = 'Grating vector x [1/px]';
            app.Vector_X = uieditfield(app.Diffractiongrating_Panel, 'text');
            app.Vector_X.Editable = 'off';
            app.Sinusoidal_diffraction_grating_CheckBox = uicheckbox(app.Diffractiongrating_Panel);
            app.Sinusoidal_diffraction_grating_CheckBox.ValueChangedFcn = createCallbackFcn(app, @Sinusoidal_diffraction_grating_CheckBoxValueChanged, true);
            app.Sinusoidal_diffraction_grating_CheckBox.Text = '   On / Off';
            app.BlazedgratingLabel = uilabel(app.Diffractiongrating_Panel);
            app.BlazedgratingLabel.Text = 'Blazed grating';
            app.SinusoidalgratingLabel = uilabel(app.Diffractiongrating_Panel);
            app.SinusoidalgratingLabel.Text = 'Sinusoidal grating';
            app.Airy_beam_Panel = uipanel(app.Phase_shaping_Tab);
            app.Airy_beam_Panel.Title = 'Airy beam';
            app.Airy_CheckBox = uicheckbox(app.Airy_beam_Panel);
            app.Airy_CheckBox.ValueChangedFcn = createCallbackFcn(app, @Airy_CheckBoxValueChanged, true);
            app.Airy_CheckBox.Text = '   On / Off';
            app.HeightEditFieldLabel = uilabel(app.Airy_beam_Panel);
            app.HeightEditFieldLabel.Text = 'Height';
            app.Airy_Height_EditField = uieditfield(app.Airy_beam_Panel, 'numeric');
            app.Airy_Height_EditField.ValueChangedFcn = createCallbackFcn(app, @Airy_Height_EditFieldValueChanged, true);
            app.WidthEditFieldLabel = uilabel(app.Airy_beam_Panel);
            app.WidthEditFieldLabel.Text = 'Width';
            app.Airy_Width_EditField = uieditfield(app.Airy_beam_Panel, 'numeric');
            app.Airy_Width_EditField.ValueChangedFcn = createCallbackFcn(app, @Airy_Width_EditFieldValueChanged, true);
            app.TabGroup2 = uitabgroup(app.Phase_shaping_Tab);
            app.TransformationTab = uitab(app.TabGroup2);
            app.TransformationTab.Title = 'Transformation';
            app.Transformation_Panel = uipanel(app.TransformationTab);
            app.Transformation_Panel.Title = 'Translation and Rotation';
            app.TranslationxSpinnerLabel = uilabel(app.Transformation_Panel);
            app.TranslationxSpinnerLabel.Text = 'Translation x';
            app.Translation_x_Spinner = uispinner(app.Transformation_Panel);
            app.Translation_x_Spinner.ValueChangedFcn = createCallbackFcn(app, @Translation_x_SpinnerValueChanged, true);
            app.TranslationySpinnerLabel = uilabel(app.Transformation_Panel);
            app.TranslationySpinnerLabel.Text = 'Translation y';
            app.Translation_y_Spinner = uispinner(app.Transformation_Panel);
            app.Translation_y_Spinner.ValueChangedFcn = createCallbackFcn(app, @Translation_y_SpinnerValueChanged, true);
            app.RotationSpinnerLabel = uilabel(app.Transformation_Panel);
            app.RotationSpinnerLabel.Text = 'Rotation';
            app.Rotation_Spinner = uispinner(app.Transformation_Panel);
            app.Rotation_Spinner.ValueChangedFcn = createCallbackFcn(app, @Rotation_SpinnerValueChanged, true);
            app.Translation_Rotation_CheckBox = uicheckbox(app.Transformation_Panel);
            app.Translation_Rotation_CheckBox.ValueChangedFcn = createCallbackFcn(app, @Translation_Rotation_CheckBoxValueChanged, true);
            app.Translation_Rotation_CheckBox.Text = '   On / Off';
            app.VortexonlySwitchLabel = uilabel(app.Transformation_Panel);
            app.VortexonlySwitchLabel.Text = 'Vortex only';
            app.VortexonlySwitch = uiswitch(app.Transformation_Panel, 'toggle');
            app.VortexonlySwitch.ValueChangedFcn = createCallbackFcn(app, @VortexonlySwitchValueChanged, true);
            app.Aperture_Panel = uipanel(app.TransformationTab);
            app.Aperture_Panel.Title = 'Aperture';
            app.DiameterpxEditFieldLabel = uilabel(app.Aperture_Panel);
            app.DiameterpxEditFieldLabel.Text = 'Diameter [px]';
            app.Aperture_Diameter = uieditfield(app.Aperture_Panel, 'numeric');
            app.Aperture_Diameter.ValueChangedFcn = createCallbackFcn(app, @Aperture_DiameterValueChanged, true);
            app.Aperture_CheckBox = uicheckbox(app.Aperture_Panel);
            app.Aperture_CheckBox.ValueChangedFcn = createCallbackFcn(app, @Aperture_CheckBoxValueChanged, true);
            app.Aperture_CheckBox.Text = '   On / Off';
            app.OtherparametersPanel = uipanel(app.Phase_shaping_Tab);
            app.OtherparametersPanel.Title = 'Other parameters';
            app.PixelsizeumEditFieldLabel = uilabel(app.OtherparametersPanel);
            app.PixelsizeumEditFieldLabel.Text = 'Pixel size [um]';
            app.PixelsizeEditField = uieditfield(app.OtherparametersPanel, 'numeric');
            app.PixelsizeEditField.ValueChangedFcn = createCallbackFcn(app, @PixelsizeEditFieldValueChanged, true);
            app.PixelsizeEditField.Tag = 'pixel_size';
            app.PixelsizeEditField.Value = 19;
            app.WavelengthnmEditFieldLabel = uilabel(app.OtherparametersPanel);
            app.WavelengthnmEditFieldLabel.Text = 'Wavelength [nm]';
            app.WavelengthEditField = uieditfield(app.OtherparametersPanel, 'numeric');
            app.WavelengthEditField.ValueChangedFcn = createCallbackFcn(app, @WavelengthEditFieldValueChanged, true);
            app.WavelengthEditField.Tag = 'lambda';
            app.WavelengthEditField.Value = 632.8;
            app.Correction_Tab = uitab(app.TabGroup);
            app.Correction_Tab.Title = 'Gerchberg-Saxton';
            app.Correction = uiaxes(app.Correction_Tab);
            title(app.Correction, '')
            xlabel(app.Correction, '')
            ylabel(app.Correction, '')
            app.Gerchberg_Saxton_Panel = uipanel(app.Correction_Tab);
            app.Gerchberg_Saxton_Panel.Title = 'Gerchberg – Saxton';
            app.GS_Button = uibutton(app.Gerchberg_Saxton_Panel, 'push');
            app.GS_Button.ButtonPushedFcn = createCallbackFcn(app, @GS_ButtonPushed, true);
            app.GS_Button.Text = 'G-S';
            app.Set_up_values_Button = uibutton(app.Gerchberg_Saxton_Panel, 'push');
            app.Set_up_values_Button.ButtonPushedFcn = createCallbackFcn(app, @Set_up_values_ButtonPushed, true);
            app.Set_up_values_Button.Text = 'Set up values';
            app.ApertureEditFieldLabel = uilabel(app.Gerchberg_Saxton_Panel);
            app.ApertureEditFieldLabel.Text = 'Aperture';
            app.GS_Aperture_EditField = uieditfield(app.Gerchberg_Saxton_Panel, 'numeric');
            app.NumberofgroovesLabel = uilabel(app.Gerchberg_Saxton_Panel);
            app.NumberofgroovesLabel.Text = 'Number of grooves';
            app.Gratingvector1pxLabel = uilabel(app.Gerchberg_Saxton_Panel);
            app.Gratingvector1pxLabel.Text = 'Grating vector [1/px]';
            app.First_grating_Button = uibutton(app.Gerchberg_Saxton_Panel, 'push');
            app.First_grating_Button.ButtonPushedFcn = createCallbackFcn(app, @First_grating_ButtonPushed, true);
            app.First_grating_Button.Text = '1st grating';
            app.Second_grating_Button = uibutton(app.Gerchberg_Saxton_Panel, 'push');
            app.Second_grating_Button.ButtonPushedFcn = createCallbackFcn(app, @Second_grating_ButtonPushed, true);
            app.Second_grating_Button.Text = '2nd grating';
            app.Axes_Button_Group = uibuttongroup(app.Gerchberg_Saxton_Panel);
            app.Axes_Button_Group.Title = 'Axes';
            app.x_Button = uiradiobutton(app.Axes_Button_Group);
            app.x_Button.Text = 'x';
            app.x_Button.Value = true;
            app.y_Button = uiradiobutton(app.Axes_Button_Group);
            app.y_Button.Text = 'y';
            app.Number_of_grooves_1st_EditField = uieditfield(app.Gerchberg_Saxton_Panel, 'text');
            app.Number_of_grooves_1st_EditField.Editable = 'off';
            app.Grating_vector_1st_EditField = uieditfield(app.Gerchberg_Saxton_Panel, 'text');
            app.Grating_vector_1st_EditField.Editable = 'off';
            app.Grating_vector_2nd_EditField = uieditfield(app.Gerchberg_Saxton_Panel, 'text');
            app.Grating_vector_2nd_EditField.Editable = 'off';
            app.Number_of_grooves_2nd_EditField = uieditfield(app.Gerchberg_Saxton_Panel, 'text');
            app.Number_of_grooves_2nd_EditField.Editable = 'off';
            app.Aperture_size_ButtonGroup = uibuttongroup(app.Gerchberg_Saxton_Panel);
            app.Aperture_size_ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @Aperture_size_ButtonGroupSelectionChanged, true);
            app.Aperture_size_ButtonGroup.Title = 'Aperture size';
            app.Original_Button = uiradiobutton(app.Aperture_size_ButtonGroup);
            app.Original_Button.Text = 'Original';
            app.Original_Button.Value = true;
            app.Half_Button = uiradiobutton(app.Aperture_size_ButtonGroup);
            app.Half_Button.Text = 'Half';
            app.Quarter_Button = uiradiobutton(app.Aperture_size_ButtonGroup);
            app.Quarter_Button.Text = 'Quarter';
            app.Other_Button = uiradiobutton(app.Aperture_size_ButtonGroup);
            app.Other_Button.Text = 'Other';
            app.PaddingEditFieldLabel = uilabel(app.Gerchberg_Saxton_Panel);
            app.PaddingEditFieldLabel.Text = 'Padding';
            app.Padding_EditField = uieditfield(app.Gerchberg_Saxton_Panel, 'text');
            app.Padding_EditField.Editable = 'off';
            app.Load_correction_ButtonGroup = uibuttongroup(app.Gerchberg_Saxton_Panel);
            app.Load_correction_ButtonGroup.Title = 'Load correction';
            app.plus = uiradiobutton(app.Load_correction_ButtonGroup);
            app.plus.Text = '  +';
            app.plus.Value = true;
            app.minus = uiradiobutton(app.Load_correction_ButtonGroup);
            app.minus.Text = '  -';
            app.On_off_correction = uicheckbox(app.Load_correction_ButtonGroup);
            app.On_off_correction.ValueChangedFcn = createCallbackFcn(app, @On_off_correctionValueChanged, true);
            app.On_off_correction.Text = '   On / Off';
            app.IterationsLabel = uilabel(app.Gerchberg_Saxton_Panel);
            app.IterationsLabel.Text = 'Iterations';
            app.Iterations_EditField = uieditfield(app.Gerchberg_Saxton_Panel, 'numeric');
            app.Iterations_EditField.ValueChangedFcn = createCallbackFcn(app, @Iterations_EditFieldValueChanged, true);
            app.Iterations_EditField.Value = 5;
            app.Current_iteration_number_EditField = uieditfield(app.Gerchberg_Saxton_Panel, 'numeric');
            app.Current_iteration_number_EditField.Editable = 'off';
            app.CorrectionLabel_2 = uilabel(app.Correction_Tab);
            app.CorrectionLabel_2.Text = 'Correction';
            app.ZernikepolynomialsTab = uitab(app.TabGroup);
            app.ZernikepolynomialsTab.Title = 'Zernike polynomials';
            app.Fresnel_lens_Panel_2 = uipanel(app.ZernikepolynomialsTab);
            app.Fresnel_lens_Panel_2.Title = 'Zernike polynomials';
            app.On_off_Zernike = uicheckbox(app.Fresnel_lens_Panel_2);
            app.On_off_Zernike.ValueChangedFcn = createCallbackFcn(app, @On_off_ZernikeValueChanged, true);
            app.On_off_Zernike.Text = '   On / Off';
            app.Value_Label = uilabel(app.Fresnel_lens_Panel_2);
            app.Value_Label.Text = 'Value';
            app.Name_Label = uilabel(app.Fresnel_lens_Panel_2);
            app.Name_Label.Text = 'Indices           Name';
            app.Label_3 = uilabel(app.Fresnel_lens_Panel_2);
            app.Label_3.Text = '0  0';
            app.Label_4 = uilabel(app.Fresnel_lens_Panel_2);
            app.Label_4.Text = '-1  1';
            app.Label_5 = uilabel(app.Fresnel_lens_Panel_2);
            app.Label_5.Text = '1 1';
            app.Label_6 = uilabel(app.Fresnel_lens_Panel_2);
            app.Label_6.Text = '-2  2';
            app.Label_7 = uilabel(app.Fresnel_lens_Panel_2);
            app.Label_7.Text = '0  2';
            app.Label_8 = uilabel(app.Fresnel_lens_Panel_2);
            app.Label_8.Text = '2  2';
            app.Label_9 = uilabel(app.Fresnel_lens_Panel_2);
            app.Label_9.Text = '-3  3';
            app.Label_10 = uilabel(app.Fresnel_lens_Panel_2);
            app.Label_10.Text = '-1  3';
            app.Label_11 = uilabel(app.Fresnel_lens_Panel_2);
            app.Label_11.Text = '1  3';
            app.Label_12 = uilabel(app.Fresnel_lens_Panel_2);
            app.Label_12.Text = '3  3';
            app.PistonEditFieldLabel = uilabel(app.Fresnel_lens_Panel_2);
            app.PistonEditFieldLabel.Text = 'Piston';
            app.Piston_EditField = uieditfield(app.Fresnel_lens_Panel_2, 'numeric');
            app.Piston_EditField.ValueChangedFcn = createCallbackFcn(app, @Piston_EditFieldValueChanged, true);
            app.YTiltEditFieldLabel = uilabel(app.Fresnel_lens_Panel_2);
            app.YTiltEditFieldLabel.Text = 'Y Tilt';
            app.Y_Tilt_EditField = uieditfield(app.Fresnel_lens_Panel_2, 'numeric');
            app.Y_Tilt_EditField.ValueChangedFcn = createCallbackFcn(app, @Y_Tilt_EditFieldValueChanged, true);
            app.XTiltEditFieldLabel = uilabel(app.Fresnel_lens_Panel_2);
            app.XTiltEditFieldLabel.Text = 'X Tilt';
            app.X_Tilt_EditField = uieditfield(app.Fresnel_lens_Panel_2, 'numeric');
            app.X_Tilt_EditField.ValueChangedFcn = createCallbackFcn(app, @X_Tilt_EditFieldValueChanged, true);
            app.ObliqueastgmEditFieldLabel = uilabel(app.Fresnel_lens_Panel_2);
            app.ObliqueastgmEditFieldLabel.Text = 'Oblique astgm';
            app.Oblique_astgm_EditField = uieditfield(app.Fresnel_lens_Panel_2, 'numeric');
            app.Oblique_astgm_EditField.ValueChangedFcn = createCallbackFcn(app, @Oblique_astgm_EditFieldValueChanged, true);
            app.DefocusLabel = uilabel(app.Fresnel_lens_Panel_2);
            app.DefocusLabel.Text = 'Defocus';
            app.Defocuse_EditField = uieditfield(app.Fresnel_lens_Panel_2, 'numeric');
            app.Defocuse_EditField.ValueChangedFcn = createCallbackFcn(app, @Defocuse_EditFieldValueChanged, true);
            app.VerticalastgmLabel = uilabel(app.Fresnel_lens_Panel_2);
            app.VerticalastgmLabel.Text = 'Vertical astgm';
            app.Vertical_EditField = uieditfield(app.Fresnel_lens_Panel_2, 'numeric');
            app.Vertical_EditField.ValueChangedFcn = createCallbackFcn(app, @Vertical_EditFieldValueChanged, true);
            app.VerticaltrefoilEditFieldLabel = uilabel(app.Fresnel_lens_Panel_2);
            app.VerticaltrefoilEditFieldLabel.Text = 'Vertical trefoil';
            app.Vertical_trefoil_EditField = uieditfield(app.Fresnel_lens_Panel_2, 'numeric');
            app.Vertical_trefoil_EditField.ValueChangedFcn = createCallbackFcn(app, @Vertical_trefoil_EditFieldValueChanged, true);
            app.VerticalcomaEditFieldLabel = uilabel(app.Fresnel_lens_Panel_2);
            app.VerticalcomaEditFieldLabel.Text = 'Vertical coma';
            app.Vertical_coma_EditField = uieditfield(app.Fresnel_lens_Panel_2, 'numeric');
            app.Vertical_coma_EditField.ValueChangedFcn = createCallbackFcn(app, @Vertical_coma_EditFieldValueChanged, true);
            app.HorizontalcomaEditFieldLabel = uilabel(app.Fresnel_lens_Panel_2);
            app.HorizontalcomaEditFieldLabel.Text = 'Horizontal coma';
            app.Horizontal_coma_EditField = uieditfield(app.Fresnel_lens_Panel_2, 'numeric');
            app.Horizontal_coma_EditField.ValueChangedFcn = createCallbackFcn(app, @Horizontal_coma_EditFieldValueChanged, true);
            app.HorizontaltrefoilEditFieldLabel = uilabel(app.Fresnel_lens_Panel_2);
            app.HorizontaltrefoilEditFieldLabel.Text = 'Horizontal trefoil';
            app.Horizontal_trefoil_EditField = uieditfield(app.Fresnel_lens_Panel_2, 'numeric');
            app.Horizontal_trefoil_EditField.ValueChangedFcn = createCallbackFcn(app, @Horizontal_trefoil_EditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LBSA

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end